classdef CorAnaAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FSDAdynamicinteractiveCAplotUIFigure  matlab.ui.Figure
        EditFieldConfLev         matlab.ui.control.NumericEditField
        Label                    matlab.ui.control.Label
        ListBoxColsEll           matlab.ui.control.ListBox
        ColswithellListBoxLabel  matlab.ui.control.Label
        ListBoxRowsEll           matlab.ui.control.ListBox
        ChkBoxshowConfEll        matlab.ui.control.CheckBox
        RowswithellListBoxLabel  matlab.ui.control.Label
        TabGroup                 matlab.ui.container.TabGroup
        MCD                      matlab.ui.container.Tab
        SliderBDP                matlab.ui.control.Slider
        SliderBDPLabel           matlab.ui.control.Label
        ForwardSearch            matlab.ui.container.Tab
        SliderFS                 matlab.ui.control.Slider
        SliderFSLabel            matlab.ui.control.Label
        ChkBoxRevSignCA2         matlab.ui.control.CheckBox
        ChkBoxRevSignCA1         matlab.ui.control.CheckBox
        ResetButton              matlab.ui.control.Button
        SuppColsListBox          matlab.ui.control.ListBox
        SuppColsListBoxLabel     matlab.ui.control.Label
        SuppRowsListBox          matlab.ui.control.ListBox
        SuppRowsListBoxLabel     matlab.ui.control.Label
        ChkBoxshowColPointsSup   matlab.ui.control.CheckBox
        ChkBoxshowRowPointsSup   matlab.ui.control.CheckBox
        MapTypeDropDown          matlab.ui.control.DropDown
        MapTypeDropDownLabel     matlab.ui.control.Label
        ChkBoxshowColPoints      matlab.ui.control.CheckBox
        ChkBoxshowMoonPlot       matlab.ui.control.CheckBox
        ChkBoxshowRowPoints      matlab.ui.control.CheckBox
        SliderAlpha              matlab.ui.control.Slider
        SliderAlphaLabel         matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end


    properties (Access = public)
        % Property % Description
        Xtable='';
        % alpha parameter in the slider
        alpha='';
        % typeCA
        typeCA='rowprincipal'
        % slider bdp
        BDPslider=true;
        % slider FS
        FSslider=false;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Xtable, varargin)
            [I,J]=size(Xtable);
            if istable(Xtable) || istimetable(Xtable)
                colnames=Xtable.Properties.VariableNames;
                if istable(Xtable)
                    rownames=Xtable.Properties.RowNames;
                else
                    rownames=string(Xtable.Properties.RowTimes);
                end
                N=table2array(Xtable);
            else
                colnames=cellstr(num2str((1:J)','Y%d'));
                rownames=cellstr(num2str((1:I)','%d'));
                Xtable=array2table(X,"RowNames",rownames,"VariableNames",colnames);
                N=Xtable;
            end

            n=sum(N,'all');

            app.alpha = 0.3;

            if ~isempty(app.SuppColsListBox.Value)
                selCols=setdiff(1:J,app.SuppColsListBox.Value);
                nred=sum(N(:,selCols),'all');
                ini=round(nred/4);
            else
                selCols=1:J;
                nred=n;
                ini=round(n/4);
            end

            % Set sliders for bdp and subset size
            selsteps=round(nred*[0.25 0.33 0.4 0.5:0.1:1]);
            app.SliderFS.Limits=[ini nred];
            app.SliderFS.MajorTicks=selsteps;
            app.SliderFS.MinorTicks=round(nred*[0.3 0.45:0.10:0.95]);
            app.SliderFS.MajorTickLabels={'1/4' '1/3' '0.4' '1/2' '0.6' '0.7' '0.8' '0.9' '1'};
            app.SliderFSLabel.Text={'Subset size/n' ['n=' num2str(nred) '     ']};
            app.SliderBDPLabel.Text=['(n=' num2str(nred) ') bdp'];

            % Fill the list box just the very first time the APP is
            % launched
            if isempty(app.SuppRowsListBox.Items)
                app.SuppRowsListBox.Items=rownames;
                app.SuppRowsListBox.ItemsData=1:I;
                app.SuppRowsListBox.Multiselect='on';
                app.SuppColsListBox.Items=colnames;
                app.SuppColsListBox.ItemsData=1:J;
                app.SuppColsListBox.Multiselect='on';
            end

            app.typeCA='bothprincipal';
            app.Xtable=Xtable;


            %             % Optional arguments TODO
            %             if nargin>2
            %
            %                 options=struct('BDPslider',BDPslider,'FSslider',FSslider);
            %
            %                 UserOptions=varargin(1:2:length(varargin));
            %                 if ~isempty(UserOptions)
            %                     % Check if number of supplied options is valid
            %                     if length(varargin) ~= 2*length(UserOptions)
            %                         error('FSDA:CorAnaAPP:WrongInputOpt','Number of supplied options is invalid. Probably values for some parameters are missing.');
            %                     end
            %                     % Check if user options are valid options
            %                     chkoptions(options,UserOptions)
            %                 end
            %
            %                 % Write in structure 'options' the options chosen by the user
            %                 for i=1:2:length(varargin)
            %                     options.(varargin{i})=varargin{i+1};
            %                 end
            %                 BDPslider=options.BDPslider;
            %                 FSslider=options.FSslider;
            %             end

            plots=struct;
            if strcmp(app.MapTypeDropDown.Value,'numeric value α in [0 1]')
                app.SliderAlpha.Visible='on';
                app.SliderAlphaLabel.Visible='on';
                plots.alpha=app.SliderAlpha.Value;
            else
                app.SliderAlpha.Visible='off';
                app.SliderAlphaLabel.Visible='off';
                plots.alpha=app.MapTypeDropDown.Value;
            end

            if app.ChkBoxshowRowPoints.Value==true
                plots.ColorRows='b';
            else
                plots.ColorRows='w';
            end

            if app.ChkBoxshowRowPointsSup.Value==true
                plots.ColorRowsSup='b';
            else
                plots.ColorRowsSup='w';
            end


            if app.ChkBoxshowColPoints.Value==true
                plots.ColorCols='r';
            else
                plots.ColorCols='w';
            end

            if app.ChkBoxshowColPointsSup.Value==true
                plots.ColorColsSup='r';
            else
                plots.ColorColsSup='w';
            end


            if app.BDPslider==true  % slider MCD case
                if (0.5-app.SliderBDP.Value)~=0
                    % if bdp >0 call mcdCorAna
                    outrob=mcdCorAna(N(:,selCols),'bdp',0.5-app.SliderBDP.Value);
                    if ~isempty(outrob.outliers)
                        app.SuppRowsListBox.Value=[];
                        app.SuppRowsListBox.Value=outrob.outliers;
                    end
                else % no unit is declared as outlier given that bdp=0
                    app.SuppRowsListBox.Value=[];
                    app.SuppRowsListBox.Value=[];
                end


                Sup=struct;
                if ~isempty(app.SuppRowsListBox.Value)
                    Sup.r=app.SuppRowsListBox.Value;
                end
                if ~isempty(app.SuppColsListBox.Value)
                    Sup.c=app.SuppColsListBox.Value;
                end
                out=CorAna(Xtable,'plots',0,'Sup',Sup);

            elseif  round(app.SliderFS.Value)~=nred && app.FSslider==true % FS case
                % TODO case in which not all cols are considered
                RAW=mcdCorAna(N(:,selCols),'bdp',0.5,'plots',0);
                ini=round(sum(sum(RAW.N))/5);
                outFS=FSCorAnaeda(RAW,'init',ini,'plots',0);

                pos=find(outFS.Un(:,1)==round(app.SliderFS.Value));
                bsel=outFS.BB(:,pos+1);
                units0Weight=isnan(bsel);
                bsel(units0Weight)=0;
                Nsel=N(:,selCols).*bsel;
                unitsinside=sum(Nsel,2)>0;
                % Niter=Nsel(unitsinside,:);
                NiterWithSupCols=N(unitsinside,:);
                Nitertable=array2table(NiterWithSupCols,"RowNames",rownames(unitsinside),"VariableNames",colnames);
                NselSup=N(:,selCols).*(1-bsel);
                unitsoutside=sum(NselSup,2)>0;
                % Note that we are in the situation in which  app.SliderFS.Value~=n
                % therefore there are always (partial) supplementary rows
                NiterSup=NselSup(unitsoutside,:);
                NiterSuptable=array2table(NiterSup,"RowNames",rownames(unitsoutside),"VariableNames",colnames(selCols));
                seqI=1:I;
                app.SuppRowsListBox.Value=[];
                app.SuppRowsListBox.Value=seqI(unitsoutside);
                Sup=struct;
                Sup.r=NiterSuptable;
                % Keep into account the supplementary columns
                if ~isempty(app.SuppColsListBox.Value)
                    Sup.c=app.SuppColsListBox.Value;
                end
                out=CorAna(Nitertable,'plots',0,'Sup',Sup);
            elseif round(app.SliderFS.Value)==nred && app.FSslider==true % FS case all units considered
                app.SuppRowsListBox.Value=[];
                Sup=struct;
                if ~isempty(app.SuppColsListBox.Value)
                    Sup.c=app.SuppColsListBox.Value;
                end
                out=CorAna(Xtable,'plots',0,'Sup',Sup);

            else
                % There is no FS or no MCD
                % There are possibly just supplementary rows or supp cols
                if isempty(app.SuppRowsListBox.Value) &&  isempty(app.SuppColsListBox.Value)
                    out=CorAna(Xtable,'plots',0);
                else
                    Sup=struct;
                    if  ~isempty(app.SuppColsListBox.Value)
                        Sup.c=app.SuppColsListBox.Value;
                    end
                    if  ~isempty(app.SuppRowsListBox.Value)
                        Sup.r=app.SuppRowsListBox.Value;
                    end
                    out=CorAna(Xtable,'plots',0,'Sup',Sup);
                end
            end
            % disp('sulcols')
            % disp(app.SuppColsListBox.Value)


            delete(app.UIAxes.Children)

            changedimsign=[false false];
            if app.ChkBoxRevSignCA1.Value == true
                changedimsign(1)=true;
            end
            if app.ChkBoxRevSignCA2.Value == true
                changedimsign(2)=true;
            end

            if app.ChkBoxshowConfEll.Value == true
                app.ListBoxRowsEll.Visible ="on";
                app.ListBoxColsEll.Visible ="on";
                app.EditFieldConfLev.Visible ="on";
            else
                app.ListBoxRowsEll.Visible ="off";
                app.ListBoxColsEll.Visible ="off";
                 app.EditFieldConfLev.Visible ="off";
            end

            if app.ChkBoxshowMoonPlot.Value == true
                moonplot(out,'h',app.UIAxes,'plots',plots,'changedimsign',changedimsign)
            else

                if app.ChkBoxshowConfEll.Value==true
                    rownamsel=out.Ntable.Properties.RowNames;
                    rownamsel(end+1)={''};
                    app.ListBoxRowsEll.Items=rownamsel;
                    app.ListBoxRowsEll.ItemsData=1:length(rownamsel)+1;

                    colnamsel=out.Ntable.Properties.VariableNames;
                    colnamsel(end+1)={''};
                    app.ListBoxColsEll.Items=colnamsel;
                    app.ListBoxColsEll.ItemsData=1:length(colnamsel)+1;

                    confellipse=struct;
                    % Get the selected entries
                    rowssel=app.ListBoxRowsEll.Value;
                    % If the empty choice has also been selected remove it
                    if ~isempty(rowssel)
                        rowssel(rowssel>length(rownamsel)-1)=[];
                    end
                    confellipse.selRows=rowssel;

                    % Get the selected entries
                    colssel=app.ListBoxColsEll.Value;
                    % If the empty choice has also been selected remove it
                    if ~isempty(colssel)
                        colssel(colssel>length(colnamsel)-1)=[];
                    end
                    confellipse.selCols=colssel;
                    confellipse.conflev=app.EditFieldConfLev.Value/100;
                else
                    confellipse=0;
                end

                CorAnaplot(out,'h',app.UIAxes,'plots',plots,'changedimsign',changedimsign,'confellipse',confellipse)
            end

        end

        % Value changed function: SliderAlpha
        function SliderAlphaValueChanged(app, event)
            startupFcn(app, app.Xtable)
        end

        % Value changed function: SliderBDP
        function SliderBDPValueChanged(app, event)
            app.BDPslider=true;
            app.FSslider=false;
            startupFcn(app, app.Xtable)
        end

        % Value changed function: ChkBoxshowRowPoints
        function ChkBoxshowRowPointsValueChanged(app, event)
            startupFcn(app, app.Xtable)
        end

        % Value changed function: ChkBoxshowMoonPlot
        function ChkBoxshowMoonPlotValueChanged(app, event)
            startupFcn(app, app.Xtable)
        end

        % Value changed function: ChkBoxshowColPoints
        function ChkBoxshowColPointsValueChanged(app, event)
            startupFcn(app, app.Xtable)
        end

        % Value changed function: MapTypeDropDown
        function MapTypeDropDownValueChanged(app, event)
            startupFcn(app, app.Xtable)
        end

        % Value changed function: SuppRowsListBox
        function SuppRowsListBoxValueChanged(app, event)
            % If the user selects manually supplementary rows therefore
            % there is no MCD and no FS
            app.BDPslider=false;
            app.FSslider=false;
            startupFcn(app, app.Xtable)
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.SuppRowsListBox.Value=[];
            app.SuppColsListBox.Value=[];
            n=sum(app.Xtable{:,:},'all');


            % Set sliders for bdp and subset size
            selsteps=round(n*[0.25 0.33 0.4 0.5:0.1:1]);
            app.SliderFS.Limits=[round(n/4) n];
            app.SliderFS.MajorTicks=selsteps;
            app.SliderFS.MinorTicks=round(n*[0.3 0.45:0.10:0.95]);
            app.SliderFS.MajorTickLabels={'1/4' '1/3' '0.4' '1/2' '0.6' '0.7' '0.8' '0.9' '1'};
            app.SliderFS.Value=n;
            app.SliderFSLabel.Text=['Subset size/n; n=' num2str(n)];
            app.SliderBDP.Value=0.5; % note that app.SliderBDP.Value=0.5 corresponds to bdp=0
            app.SliderBDPLabel.Text=['(n=' num2str(n) ') bdp'];


            % reset check box related to sign of latent components
            app.ChkBoxRevSignCA1.Value=false;
            app.ChkBoxRevSignCA2.Value=false;
            % reset check boxes relate to visualization of row (supp) and
            % column (supp) points
            app.ChkBoxshowRowPoints.Value=true;
            app.ChkBoxshowRowPointsSup.Value=true;
            app.ChkBoxshowColPoints.Value=true;
            app.ChkBoxshowColPointsSup.Value=true;
            app.ChkBoxshowConfEll.Value=false;
            app.ListBoxRowsEll.Visible ="off";
            app.ListBoxRowsEll.Value={};
            app.ListBoxColsEll.Visible ="off";
            app.ListBoxColsEll.Value={};
            app.EditFieldConfLev.Visible = "off";
            out=CorAna(app.Xtable,'plots',0);
            delete(app.UIAxes.Children)
            if app.ChkBoxshowMoonPlot.Value==false
                CorAnaplot(out,'h',app.UIAxes);
            else
                moonplot(out,'h',app.UIAxes);
            end
            %  startupFcn(app, app.Xtable)
        end

        % Value changed function: SuppColsListBox
        function SuppColsListBoxValueChanged(app, event)
            startupFcn(app, app.Xtable)
        end

        % Value changed function: ChkBoxshowRowPointsSup
        function ChkBoxshowRowPointsSupValueChanged(app, event)
            startupFcn(app, app.Xtable)
        end

        % Value changed function: ChkBoxshowColPointsSup
        function ChkBoxshowColPointsSupValueChanged(app, event)
            startupFcn(app, app.Xtable)
        end

        % Value changed function: ChkBoxRevSignCA1
        function ChkBoxRevSignCA1ValueChanged(app, event)
            startupFcn(app, app.Xtable)
        end

        % Value changed function: ChkBoxRevSignCA2
        function ChkBoxRevSignCA2ValueChanged(app, event)
            startupFcn(app, app.Xtable)
        end

        % Value changed function: SliderFS
        function SliderFSValueChanged(app, event)
            app.BDPslider=false;
            app.FSslider=true;
            startupFcn(app, app.Xtable)
        end

        % Value changed function: ChkBoxshowConfEll
        function ChkBoxshowConfEllValueChanged(app, event)
            startupFcn(app, app.Xtable)
        end

        % Value changed function: ListBoxRowsEll
        function ListBoxRowsEllValueChanged(app, event)
            startupFcn(app, app.Xtable)
        end

        % Value changed function: ListBoxColsEll
        function ListBoxColsEllValueChanged(app, event)
            startupFcn(app, app.Xtable)
        end

        % Value changed function: EditFieldConfLev
        function EditFieldConfLevValueChanged(app, event)
            startupFcn(app, app.Xtable)         
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FSDAdynamicinteractiveCAplotUIFigure = uifigure('Visible', 'off');
            app.FSDAdynamicinteractiveCAplotUIFigure.Name = 'FSDA dynamic interactive biplot';
            app.UIAxes = uiaxes(app.FSDAdynamicinteractiveCAplotUIFigure);
            title(app.UIAxes, ''' ''')
            xlabel(app.UIAxes, 'CA1')
            ylabel(app.UIAxes, 'CA2')
            app.SliderAlphaLabel = uilabel(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.SliderAlphaLabel.Text = 'α';
            app.SliderAlpha = uislider(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.SliderAlpha.Limits = [0 1];
            app.SliderAlpha.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.SliderAlpha.ValueChangedFcn = createCallbackFcn(app, @SliderAlphaValueChanged, true);
            app.SliderAlpha.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.SliderAlpha.Tooltip = {'α=0 corresponds to columnprincipal,'; 'α=1 corresponds to rowprincipal:'; 'α=0.5 corresponds to symbiplot'};
            app.SliderAlpha.Value = 0.3;
            app.ChkBoxshowRowPoints = uicheckbox(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.ChkBoxshowRowPoints.ValueChangedFcn = createCallbackFcn(app, @ChkBoxshowRowPointsValueChanged, true);
            app.ChkBoxshowRowPoints.Tooltip = {'If this checkbox is unchecked row points and associated labels are shown with white color'};
            app.ChkBoxshowRowPoints.Text = 'showRowPoints';
            app.ChkBoxshowRowPoints.Value = true;
            app.ChkBoxshowMoonPlot = uicheckbox(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.ChkBoxshowMoonPlot.ValueChangedFcn = createCallbackFcn(app, @ChkBoxshowMoonPlotValueChanged, true);
            app.ChkBoxshowMoonPlot.Tooltip = {'Show the moonplot instead of the traditional correspondence analysis plot'};
            app.ChkBoxshowMoonPlot.Text = 'Moonplot';
            app.ChkBoxshowColPoints = uicheckbox(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.ChkBoxshowColPoints.ValueChangedFcn = createCallbackFcn(app, @ChkBoxshowColPointsValueChanged, true);
            app.ChkBoxshowColPoints.Tooltip = {'If this checkbox is unchecked column points and associated labels are shown with white color'};
            app.ChkBoxshowColPoints.Text = 'showColPoints';
            app.ChkBoxshowColPoints.Value = true;
            app.MapTypeDropDownLabel = uilabel(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.MapTypeDropDownLabel.Text = 'MapType';
            app.MapTypeDropDown = uidropdown(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.MapTypeDropDown.Items = {'rowprincipal', 'colprincipal', 'symbiplot', 'bothprincipal', 'bothstandard', 'rowgab', 'rowgreen', 'colgreen', 'numeric value α in [0 1]'};
            app.MapTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @MapTypeDropDownValueChanged, true);
            app.MapTypeDropDown.Tooltip = {'Specify the type of coordinates to use in the plot'; 'For additional details please see'; 'http://rosa.unipr.it/FSDA/coranaplot.html'; ''};
            app.MapTypeDropDown.Value = 'bothprincipal';
            app.ChkBoxshowRowPointsSup = uicheckbox(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.ChkBoxshowRowPointsSup.ValueChangedFcn = createCallbackFcn(app, @ChkBoxshowRowPointsSupValueChanged, true);
            app.ChkBoxshowRowPointsSup.Tooltip = {'If this checkbox is unchecked supplementary row points and associated labels are shown with white color'};
            app.ChkBoxshowRowPointsSup.Text = 'show Supp RowPoints';
            app.ChkBoxshowRowPointsSup.Value = true;
            app.ChkBoxshowColPointsSup = uicheckbox(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.ChkBoxshowColPointsSup.ValueChangedFcn = createCallbackFcn(app, @ChkBoxshowColPointsSupValueChanged, true);
            app.ChkBoxshowColPointsSup.Tooltip = {'If this checkbox is unchecked supplementary column points and associated labels are shown with white color'};
            app.ChkBoxshowColPointsSup.Text = 'show Supp ColPoints';
            app.ChkBoxshowColPointsSup.Value = true;
            app.SuppRowsListBoxLabel = uilabel(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.SuppRowsListBoxLabel.Text = 'SuppRows';
            app.SuppRowsListBox = uilistbox(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.SuppRowsListBox.Items = {};
            app.SuppRowsListBox.ValueChangedFcn = createCallbackFcn(app, @SuppRowsListBoxValueChanged, true);
            app.SuppRowsListBox.Tooltip = {'Select the rows points which must be treated as supplementary units'};
            app.SuppRowsListBox.Value = {};
            app.SuppColsListBoxLabel = uilabel(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.SuppColsListBoxLabel.Text = 'SuppCols';
            app.SuppColsListBox = uilistbox(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.SuppColsListBox.Items = {};
            app.SuppColsListBox.Multiselect = 'on';
            app.SuppColsListBox.ValueChangedFcn = createCallbackFcn(app, @SuppColsListBoxValueChanged, true);
            app.SuppColsListBox.Tooltip = {'Select the column points which must be treated as supplementary units'};
            app.SuppColsListBox.Value = {};
            app.ResetButton = uibutton(app.FSDAdynamicinteractiveCAplotUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Tooltip = {'Clicking on Reset clears the selections in the listboxes of Supplementary Row points and Supplementary  Column points, sets the bdp to 0 (standard non robust analysis), the subset size to n and unselects the two check boxes of sign..'};
            app.ResetButton.Text = 'Reset';
            app.ChkBoxRevSignCA1 = uicheckbox(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.ChkBoxRevSignCA1.ValueChangedFcn = createCallbackFcn(app, @ChkBoxRevSignCA1ValueChanged, true);
            app.ChkBoxRevSignCA1.Tooltip = {'Change sign of the first latent dimension'};
            app.ChkBoxRevSignCA1.Text = 'Rev sign CA1';
            app.ChkBoxRevSignCA2 = uicheckbox(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.ChkBoxRevSignCA2.ValueChangedFcn = createCallbackFcn(app, @ChkBoxRevSignCA2ValueChanged, true);
            app.ChkBoxRevSignCA2.Tooltip = {'Change sign of the second latent dimension'};
            app.ChkBoxRevSignCA2.Text = 'Rev sign CA2';
            app.TabGroup = uitabgroup(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.MCD = uitab(app.TabGroup);
            app.MCD.Title = 'MCD';
            app.SliderBDPLabel = uilabel(app.MCD);
            app.SliderBDPLabel.Text = 'bdp';
            app.SliderBDP = uislider(app.MCD);
            app.SliderBDP.Limits = [0 0.5];
            app.SliderBDP.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5];
            app.SliderBDP.MajorTickLabels = {'0.5', '0.4', '0.3', '0.2', '0.1', '0'};
            app.SliderBDP.ValueChangedFcn = createCallbackFcn(app, @SliderBDPValueChanged, true);
            app.SliderBDP.MinorTicks = [0 0.05 0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5];
            app.SliderBDP.Tooltip = {'Select the value of breakdown point you wish to use in otder to compute the MCD. The units flagged as outliers at the 1 per cent simultaneous confidence level will be shown in the plot as supplementary row points '};
            app.SliderBDP.Value = 0.5;
            app.ForwardSearch = uitab(app.TabGroup);
            app.ForwardSearch.Title = 'Forward Search';
            app.SliderFSLabel = uilabel(app.ForwardSearch);
            app.SliderFSLabel.Text = {'Subset size/n'; ''};
            app.SliderFS = uislider(app.ForwardSearch);
            app.SliderFS.Limits = [-1 10000000];
            app.SliderFS.MajorTicks = [];
            app.SliderFS.ValueChangedFcn = createCallbackFcn(app, @SliderFSValueChanged, true);
            app.SliderFS.MinorTicks = [];
            app.SliderFS.Tooltip = {'Select the value of subset size you wish to use in order to compute CA for that subset size. The units outside the subset  are shown in the plot as supplementary row points. The row which is both active and supplementary unit in the plot is shown both with filled and unfilled circle. The size of the filled circle is directly proportional to the number of units in that partciular row which have been left outside the subset. '};
            app.SliderFS.Value = 10000000;
            app.RowswithellListBoxLabel = uilabel(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.RowswithellListBoxLabel.Visible = 'off';
            app.RowswithellListBoxLabel.Text = 'Rows  with ell';
            app.ChkBoxshowConfEll = uicheckbox(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.ChkBoxshowConfEll.ValueChangedFcn = createCallbackFcn(app, @ChkBoxshowConfEllValueChanged, true);
            app.ChkBoxshowConfEll.Tooltip = {'If this checkbox is unchecked row points and associated labels are shown with white color'};
            app.ChkBoxshowConfEll.Text = 'Add  conf ellipses';
            app.ListBoxRowsEll = uilistbox(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.ListBoxRowsEll.Items = {};
            app.ListBoxRowsEll.Multiselect = 'on';
            app.ListBoxRowsEll.ValueChangedFcn = createCallbackFcn(app, @ListBoxRowsEllValueChanged, true);
            app.ListBoxRowsEll.Visible = 'off';
            app.ListBoxRowsEll.Tooltip = {'Select the actve rows which must have a confidence ellipse'};
            app.ListBoxRowsEll.Value = {};
            app.ColswithellListBoxLabel = uilabel(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.ColswithellListBoxLabel.Visible = 'off';
            app.ColswithellListBoxLabel.Text = 'Cols with ell';
            app.ListBoxColsEll = uilistbox(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.ListBoxColsEll.Items = {};
            app.ListBoxColsEll.Multiselect = 'on';
            app.ListBoxColsEll.ValueChangedFcn = createCallbackFcn(app, @ListBoxColsEllValueChanged, true);
            app.ListBoxColsEll.Visible = 'off';
            app.ListBoxColsEll.Tooltip = {'Select the actre columns which must have a confidence ellipse'};
            app.ListBoxColsEll.Value = {};
            app.Label = uilabel(app.FSDAdynamicinteractiveCAplotUIFigure);
            app.Label.Visible = 'off';
            app.Label.Text = '%';
            app.EditFieldConfLev = uieditfield(app.FSDAdynamicinteractiveCAplotUIFigure, 'numeric');
            app.EditFieldConfLev.Limits = [25 99.999999];
            app.EditFieldConfLev.ValueDisplayFormat = '%11.8g';
            app.EditFieldConfLev.ValueChangedFcn = createCallbackFcn(app, @EditFieldConfLevValueChanged, true);
            app.EditFieldConfLev.Visible = 'off';
            app.EditFieldConfLev.Value = 90;
            % Show the figure after all components are created
            app.FSDAdynamicinteractiveCAplotUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CorAnaAPP(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FSDAdynamicinteractiveCAplotUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FSDAdynamicinteractiveCAplotUIFigure)
        end
    end
end