classdef biplotAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FSDAdynamicinteractivebiplotUIFigure  matlab.ui.Figure
        ChkBoxChangeSign          matlab.ui.control.CheckBox
        ChangesignPanel           matlab.ui.container.Panel
        CA1Switch                 matlab.ui.control.Switch
        CA1SwitchLabel            matlab.ui.control.Label
        CA2Switch                 matlab.ui.control.Switch
        CA2SwitchLabel            matlab.ui.control.Label
        TabGroup                  matlab.ui.container.TabGroup
        MCD                       matlab.ui.container.Tab
        SliderBDP                 matlab.ui.control.Slider
        SliderBDPLabel            matlab.ui.control.Label
        ForwardSearch             matlab.ui.container.Tab
        SliderFS                  matlab.ui.control.Slider
        SliderFSLabel             matlab.ui.control.Label
        ChkBoxRowPointsProp2Dist  matlab.ui.control.CheckBox
        ChkBoxshowArrows          matlab.ui.control.CheckBox
        ChkBoxshowRowNames        matlab.ui.control.CheckBox
        ChkBoxshowRowPoints       matlab.ui.control.CheckBox
        SliderAlpha               matlab.ui.control.Slider
        Label                     matlab.ui.control.Label
        SliderOmega               matlab.ui.control.Slider
        SliderLabel               matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end


    properties (Access = public)
        % Property % Description
        Xtable='';
        % omega parameter in the slider
        omega='';
        % alpha parameter in the slider
        alpha='';
        % boolean which specifies to standardize data in Xtable
        standardize;
        % bsb subset on which to compute the statistics
        bsb=[];
        % slider bdp
        BDPslider=false;
        % slider FS
        FSslider=false;
        ShowTabGgroup=true;
        CA1or2switch=false;
        % CA2switch=false;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Xtable, varargin)
            [n,p]=size(Xtable);
            if istable(Xtable)
                X=table2array(Xtable);
                varlabs=Xtable.Properties.VariableNames;
                rownames=Xtable.Properties.RowNames;
            else
                varlabs=cellstr(num2str((1:p)','Y%d'));
                rownames=cellstr(num2str((1:n)','%d'));
                X=Xtable;
            end

            app.omega = 0.7;
            app.alpha = 0.3;
            showRowPoints=true;
            RowPointsProp2OrthDist=false;
            showRowNames=false;
            showArrows=true;
            app.standardize=true;
            app.bsb=[];
            % Set slider for FS (subset size)
            selsteps=round(n*(0.5:0.1:1));
            app.SliderFS.Limits=[selsteps(1) n];
            app.SliderFS.MajorTicks=selsteps;
            app.SliderFS.MinorTicks=round(n*(0.55:0.10:0.95));
            app.SliderFS.MajorTickLabels={'1/2' '0.6' '0.7' '0.8' '0.9' '1'};
            app.SliderFSLabel.Text={'Subset size/n' ['n=' num2str(n) '     ']};
            bdp=[];

            if nargin>2

                options=struct('omega',app.omega,...
                    'alpha',app.alpha,'showRowPoints',showRowPoints,...
                    'RowPointsProp2OrthDist', RowPointsProp2OrthDist, ...
                    'showRowNames',showRowNames,...
                    'showArrows',showArrows,'standardize',app.standardize, ...
                    'bsb',app.bsb, 'bdp','',...
                    'BDPslider',[],'FSslider',[]);

                UserOptions=varargin(1:2:length(varargin));
                if ~isempty(UserOptions)
                    % Check if number of supplied options is valid
                    if length(varargin) ~= 2*length(UserOptions)
                        error('FSDA:biplotFS:WrongInputOpt','Number of supplied options is invalid. Probably values for some parameters are missing.');
                    end
                    % Check if user options are valid options
                    aux.chkoptions(options,UserOptions)
                end

                % Write in structure 'options' the options chosen by the user
                for i=1:2:length(varargin)
                    options.(varargin{i})=varargin{i+1};
                end
                app.alpha=options.alpha;
                % Set the supplied value of alpha in the corresponding
                % slider bar
                app.SliderAlpha.Value=app.alpha;
                app.omega=options.omega;
                % Set the supplied value of omega in the corresponding
                % slider bar
                app.SliderOmega.Value=app.omega;

                showRowPoints= options.showRowPoints;
                app.ChkBoxshowRowPoints.Value=showRowPoints;


                RowPointsProp2OrthDist=options.RowPointsProp2OrthDist;
                app.ChkBoxRowPointsProp2Dist.Value=RowPointsProp2OrthDist;

                showRowNames = options.showRowNames;
                app.ChkBoxshowRowNames.Value=showRowNames;

                showArrows = options.showArrows;
                app.ChkBoxshowArrows.Value=showArrows;
                app.standardize=options.standardize;
                app.bsb=options.bsb;
                bdp=options.bdp;
            end

            if ~isempty(bdp) && ~isempty(app.bsb)
                error('FSDA:biplotAPP:WrongInputOpt','just one between bsb and bdp has to be supplied');
            end

            texttag='texttag';

            if isempty(app.bsb) && isempty(bdp) &&  app.ShowTabGgroup==true
                app.ShowTabGgroup=false;
                app.TabGroup.Visible="off";
                showTABS=false;
            elseif app.ShowTabGgroup==false
                app.TabGroup.Visible="off";
                showTABS=false;
            else
                showTABS=true;
            end

            if app.BDPslider==true
                % find bsb using MCD
                app.bsb=true(n,1);
                bdp=0.5-options.BDPslider;
                if bdp>0
                    outMCD=mcd(X,'bdp',bdp,'conflev',1-0.01/n,'msg',0);
                    app.bsb(outMCD.outliers)=false;
                end

            elseif app.FSslider == true
                outMCD=mcd(X,'bdp',0.5,'msg',0);
                [~,unitsbsb]=FSMbsb(X,outMCD.bs,'bsbsteps',round(options.FSslider));
                % find units inside subset at step m of the FS
                app.bsb=true(n,1);
                app.bsb(isnan(unitsbsb))=false;
            else
                if isempty(app.bsb)
                    app.bsb=true(n,1);
                else
                    if ~islogical(app.bsb)
                        bsbini=false(n,1);
                        bsbini(app.bsb)=true;
                        app.bsb=bsbini;
                    end
                end
            end


            Xbsb=X(app.bsb,:);
            center=mean(Xbsb);
            nbsb=size(Xbsb,1);

            dispersion=std(Xbsb);
            if app.standardize==true
                % Create matrix of standardized data
                Z=(X-center)./dispersion;
                dispersion=1;
            else
                % Create matrix of deviations from the means
                Z=X-center;
            end

            Zbsb=Z(app.bsb,:);

            [~,Gammastar,V]=svd(Zbsb,'econ');
            % tot var =
            totvar=sum(Gammastar.^2,'all');
            percexpl1=num2str(100*Gammastar(1,1)^2/totvar,4);
            percexpl2=num2str(100*Gammastar(2,2)^2/totvar,4);

            sqn1=sqrt(nbsb-1);
            Gamma=Gammastar/sqn1;

            changedimsign=[false false];
            if app.CA1Switch.Value == true
                changedimsign(1)=true;
            end
            if app.CA2Switch.Value == true
                changedimsign(2)=true;
            end
            if changedimsign(1) ==true
                V(:,1)=-V(:,1);
            end
            if changedimsign(2) ==true
                V(:,2)=-V(:,2);
            end


            % Find orthogonal distances
            score=Z*V(:,1:2);
            Res=Z-score*V(:,1:2)';
            orthDist=sqrt(sum(Res.^2,2));

            % Just enter the following if, in the case in which the user
            % modified changed the sign of the first or second eigenvector
            % that is if the current routine is invoked by CA1SwitchValueChanged
            % or by CA1SwitchValueChanged
            
           if  app.CA1or2switch == true
                % Check whether the figure contains the loadings is open
                hFig = findobj('Type', 'figure', 'Name', 'Loadings');
                delete(hFig)

                loadings=V*Gamma./dispersion;

                xlabels=categorical(varlabs,varlabs);
                figure('Name','Loadings')
                NumComponents=2;
                for i=1:NumComponents
                    subplot(NumComponents,1,i)
                    b=bar(xlabels, loadings(:,i),'g');
                    title(['Correlations with PC' num2str(i)])
                    xtips=b(1).XData;
                    ytips=b(1).YData;
                    % The alternative instructions below only work from MATLAB
                    % 2019b
                    %   xtips = b.XEndPoints;
                    %   ytips = b.YEndPoints;
                    barlabels = string(round(loadings(:,i),2));
                    text(xtips,ytips,barlabels,'HorizontalAlignment','center',...
                        'VerticalAlignment','bottom')
                    title(['Correlations  with PC' num2str(i)])
                end

                % Reset to false app.CA1or2switch.Value
               app.CA1or2switch =false;  
            end


            % Score distance in PCA subspace of dimension k
            % larow=diag(Gamma(1:2,1:2)).^2';
            % scoreDist=sqrt(sum(score.^2./larow,2));

            % RowPoints=sqn1^app.omega*U(:,1:2)*Gamma(1:2,1:2)^app.alpha;
            RowPoints=Z*V(:,1:2)*(sqn1^(app.omega-1))*Gamma(1:2,1:2)^(app.alpha-1);

            % resetplotview(app.UIAxes,'InitializeCurrentView');
            if showRowPoints == true
                lwd=1;
                app.ChkBoxRowPointsProp2Dist.Visible="on";
                if RowPointsProp2OrthDist ==true
                    % plot(app.UIAxes,RowPoints(:,1),RowPoints(:,2),'o')
                    scatter(app.UIAxes,RowPoints(app.bsb,1),RowPoints(app.bsb,2),50,orthDist(app.bsb),'LineWidth',lwd)
                    hold(app.UIAxes,'on')
                    scatter(app.UIAxes,RowPoints(~app.bsb,1),RowPoints(~app.bsb,2),50,orthDist(~app.bsb),'filled','LineWidth',lwd)
                    % Delete all colorbars associated with the current axes
                    colorbar(app.UIAxes,'off')
                    hh=colorbar(app.UIAxes,'Location', 'eastoutside');%'eastoutside'
                    hh.Label.String='Orthogonal distance to subspace';
                    getPos = hh.Position;
                    getPos(1) = 1.07*getPos(1);
                    hh.Location = 'manual';
                    hh.Position = getPos;
                    colormap( app.UIAxes, 'jet' )
                else
                    scatter(app.UIAxes,RowPoints(app.bsb,1),RowPoints(app.bsb,2),50,"blue",'LineWidth',lwd)
                    hold(app.UIAxes,'on')
                    scatter(app.UIAxes,RowPoints(~app.bsb,1),RowPoints(~app.bsb,2),50,"blue",'filled','LineWidth',lwd)
                    hclbar=get( ancestor(app.UIAxes, 'axes'), 'Colorbar');
                    if ~isempty(hclbar)
                        hclbar.Visible="off";
                    end
                end
            else
                % Remove the points
                aaMarker=findobj(app.UIAxes,'Marker','o');
                % set(findobj(app.UIAxes,'Marker','o'),'XData',[])
                % set(findobj(app.UIAxes,'Marker','o'),'YData',[])
                for jj=1:length(aaMarker)
                    set(aaMarker(jj),'XData',[]);
                    set(aaMarker(jj),'YData',[]);
                end

                app.ChkBoxRowPointsProp2Dist.Visible="off";
                hclbar=get( ancestor(app.UIAxes, 'axes'), 'Colorbar');
                if ~isempty(hclbar)
                    hclbar.Visible="off";
                end
            end
            xlabel(app.UIAxes, ['PC1 % var explained='  percexpl1])
            ylabel(app.UIAxes, ['PC2 % var explained='  percexpl2])

            ColPoints=V(:,1:2)*(Gamma(1:2,1:2)^(1-app.alpha))*sqn1^(1-app.omega);
            zeroes = zeros(p,1);
            hold(app.UIAxes,'on')
            dx=0.001;
            dy=0.001;

            % First delete arrows before redrawing them
            delete(findobj(app.UIAxes,'Type','quiver'))
            delete(findobj(app.UIAxes,'Tag',texttag))

            if showArrows==true
                %ddperc=0.95;
                ddperc=1.05; %DDD
                quiver(app.UIAxes,zeroes,zeroes,ColPoints(:,1),ColPoints(:,2));
                text(app.UIAxes,ColPoints(:,1)*ddperc,ColPoints(:,2)*ddperc,varlabs,...
                    'Color','b','HorizontalAlignment',"center",...
                    "VerticalAlignment","baseline",'Tag',texttag);
            end


            if showRowNames ==true
                text(app.UIAxes,RowPoints(:,1)+dx,RowPoints(:,2)+dy,rownames,...
                    'Color','k','Tag',texttag);
            end

            % Store Xtable
            app.Xtable=Xtable;

            % set x and y limits
            kk=1;
            PuntiX=[RowPoints(:,1);ColPoints(:,1)];
            xlim(app.UIAxes,kk*[min(PuntiX),max(PuntiX)])
            PuntiY=[RowPoints(:,2);ColPoints(:,2)];
            ylim(app.UIAxes,kk*[min(PuntiY),max(PuntiY)])
            Xaxislim=app.UIAxes.XLim;
            % Add x axis
            line(app.UIAxes,kk*[Xaxislim(1);Xaxislim(2)], [0;0], 'Color','black');
            Yaxislim=app.UIAxes.YLim;
            % Add y axis
            line(app.UIAxes,[0;0],kk*[Yaxislim(1);Yaxislim(2)], 'Color','black');

            if showTABS==false
                FontSize=16;
                height=1.25;
                d1=text(app.UIAxes, .01, height, '$Row \; points: (\sqrt{n-1})^{\omega}U \Gamma^{  \alpha  }$',...
                    'Interpreter', 'latex','Units','normalized','FontSize',FontSize,'Tag',texttag);
                d2=text(app.UIAxes, .550, height, '$Arrow \; coordinates: (\sqrt{n-1})^{1- \omega}V \Gamma^{1-\alpha}$',...
                    'Interpreter', 'latex','Units','normalized','FontSize',FontSize,'Tag',texttag);
            else
                d1=' ';
                d2=' ';
            end

            if app.omega==1 % && app.standardize==true
                if app.alpha==0
                    disp('When \omega=1 and \alpha=0')
                    disp('Row points represent standardized principal components sqrt(n-1)*U=ZV*\Gamma^-1')
                    disp('Arrows represent correlations between variables and principal components \Gamma*V')
                    disp('The length of the arrows is the communality of the variable')
                    disp('In this case the unit circle is also shown')
                    th = 0:pi/50:2*pi;
                    xunit = cos(th);
                    yunit = sin(th) ;
                    plot(app.UIAxes, xunit, yunit);
                    daspect(app.UIAxes,[1,1,1])
                    set(d1,"Visible","off")
                    set(d2,"Visible","off")
                    text(app.UIAxes, 0.95, height, '$Row \; points: (\sqrt{n-1})U$',...
                        'Interpreter', 'latex','Units','normalized','FontSize',FontSize,'Tag',texttag);
                    text(app.UIAxes, 0.95, height-0.3, '$Arrow \; coordinates: V \Gamma$',...
                        'Interpreter', 'latex','Units','normalized','FontSize',FontSize,'Tag',texttag);

                elseif  app.alpha==1
                    disp('When \omega=1 and \alpha=1')
                    disp('Row points represent principal components sqrt(n-1)*U*\Gamma=ZV')
                    disp('Arrows represent the eigenvectors V')
                    disp('In this case the unit circle is also shown')
                    th = 0:pi/50:2*pi;
                    xunit = cos(th);
                    yunit = sin(th) ;
                    plot(app.UIAxes, xunit, yunit);
                    daspect(app.UIAxes,[1,1,1])
                    set(d1,"Visible","off")
                    set(d2,"Visible","off")
                    text(app.UIAxes, 0.95, height, '$Row \; points: (\sqrt{n-1})U\Gamma$',...
                        'Interpreter', 'latex','Units','normalized','FontSize',FontSize,'Tag',texttag);
                    text(app.UIAxes, 0.95, height-0.3, '$Arrow \; coordinates: V$',...
                        'Interpreter', 'latex','Units','normalized','FontSize',FontSize,'Tag',texttag);

                else
                    % Keep original aspect ratio
                    app.UIAxes.DataAspectRatio = [0.6595092024539878,1,1];
                end
            else
                app.UIAxes.DataAspectRatio = [0.6595092024539878,1,1];
            end


            hold(app.UIAxes,'off')
        end

        % Value changed function: SliderAlpha
        function SliderAlphaValueChanged(app, event)
            startupFcn(app, app.Xtable, 'omega',app.SliderOmega.Value,  'alpha',app.SliderAlpha.Value, ...
                'showRowPoints', app.ChkBoxshowRowPoints.Value, 'showRowNames', app.ChkBoxshowRowNames.Value,...
                'showArrows',app.ChkBoxshowArrows.Value,'standardize',app.standardize,'bsb',app.bsb, ...
                'RowPointsProp2OrthDist',app.ChkBoxRowPointsProp2Dist.Value,'BDPslider',app.SliderBDP.Value, ...
                'FSslider',app.SliderFS.Value)
        end

        % Value changed function: SliderOmega
        function SliderOmegaValueChanged(app, event)
            startupFcn(app, app.Xtable, 'omega',app.SliderOmega.Value,  'alpha',app.SliderAlpha.Value, ...
                'showRowPoints', app.ChkBoxshowRowPoints.Value, 'showRowNames', app.ChkBoxshowRowNames.Value,...
                'showArrows',app.ChkBoxshowArrows.Value,'standardize',app.standardize,'bsb',app.bsb, ...
                'RowPointsProp2OrthDist',app.ChkBoxRowPointsProp2Dist.Value,'BDPslider',app.SliderBDP.Value, ...
                'FSslider',app.SliderFS.Value)
        end

        % Value changed function: ChkBoxshowRowPoints
        function ChkBoxshowRowPointsValueChanged(app, event)
            startupFcn(app, app.Xtable, 'omega',app.SliderOmega.Value,  'alpha',app.SliderAlpha.Value, ...
                'showRowPoints', app.ChkBoxshowRowPoints.Value, 'showRowNames', app.ChkBoxshowRowNames.Value,...
                'showArrows',app.ChkBoxshowArrows.Value,'standardize',app.standardize,'bsb',app.bsb, ...
                'RowPointsProp2OrthDist',app.ChkBoxRowPointsProp2Dist.Value,'BDPslider',app.SliderBDP.Value, ...
                'FSslider',app.SliderFS.Value)
        end

        % Value changed function: ChkBoxshowRowNames
        function ChkBoxshowRowNamesValueChanged(app, event)
            startupFcn(app, app.Xtable, 'omega',app.SliderOmega.Value,  'alpha',app.SliderAlpha.Value, ...
                'showRowPoints', app.ChkBoxshowRowPoints.Value, 'showRowNames', app.ChkBoxshowRowNames.Value,...
                'showArrows',app.ChkBoxshowArrows.Value,'standardize',app.standardize,'bsb',app.bsb,...
                'RowPointsProp2OrthDist',app.ChkBoxRowPointsProp2Dist.Value,'BDPslider',app.SliderBDP.Value, ...
                'FSslider',app.SliderFS.Value)
        end

        % Value changed function: ChkBoxshowArrows
        function ChkBoxshowArrowsValueChanged(app, event)
            startupFcn(app, app.Xtable, 'omega',app.SliderOmega.Value,  'alpha',app.SliderAlpha.Value, ...
                'showRowPoints', app.ChkBoxshowRowPoints.Value, 'showRowNames', app.ChkBoxshowRowNames.Value,...
                'showArrows',app.ChkBoxshowArrows.Value,'standardize',app.standardize,'bsb',app.bsb,...
                'RowPointsProp2OrthDist',app.ChkBoxRowPointsProp2Dist.Value,'BDPslider',app.SliderBDP.Value, ...
                'FSslider',app.SliderFS.Value)
        end

        % Value changed function: ChkBoxRowPointsProp2Dist
        function ChkBoxRowPointsProp2DistValueChanged(app, event)
            startupFcn(app, app.Xtable, 'omega',app.SliderOmega.Value,  'alpha',app.SliderAlpha.Value, ...
                'showRowPoints', app.ChkBoxshowRowPoints.Value, 'showRowNames', app.ChkBoxshowRowNames.Value,...
                'showArrows',app.ChkBoxshowArrows.Value,'standardize',app.standardize,'bsb',app.bsb,...
                'RowPointsProp2OrthDist',app.ChkBoxRowPointsProp2Dist.Value,'BDPslider',app.SliderBDP.Value, ...
                'FSslider',app.SliderFS.Value)

        end

        % Value changed function: SliderBDP
        function SliderBDPValueChanged(app, event)
            app.BDPslider=true;
            app.FSslider=false;
            startupFcn(app, app.Xtable, 'omega',app.SliderOmega.Value,  'alpha',app.SliderAlpha.Value, ...
                'showRowPoints', app.ChkBoxshowRowPoints.Value, 'showRowNames', app.ChkBoxshowRowNames.Value,...
                'showArrows',app.ChkBoxshowArrows.Value,'standardize',app.standardize,'bsb',app.bsb,...
                'RowPointsProp2OrthDist',app.ChkBoxRowPointsProp2Dist.Value,'BDPslider',app.SliderBDP.Value, ...
                'FSslider',app.SliderFS.Value)
        end

        % Value changed function: SliderFS
        function SliderFSValueChanged(app, event)
            app.BDPslider=false;
            app.FSslider=true;
            startupFcn(app, app.Xtable, 'omega',app.SliderOmega.Value,  'alpha',app.SliderAlpha.Value, ...
                'showRowPoints', app.ChkBoxshowRowPoints.Value, 'showRowNames', app.ChkBoxshowRowNames.Value,...
                'showArrows',app.ChkBoxshowArrows.Value,'standardize',app.standardize,'bsb',app.bsb,...
                'RowPointsProp2OrthDist',app.ChkBoxRowPointsProp2Dist.Value,'BDPslider',app.SliderBDP.Value, ...
                'FSslider',app.SliderFS.Value)

        end

        % Close request function: FSDAdynamicinteractivebiplotUIFigure
        function FSDAdynamicinteractivebiplotUIFigureCloseRequest(app, event)
            finalbsb=app.bsb;
            % save biplotAPPout bsb
            assignin('base','bsbfinalFromAPP',finalbsb);
            delete(app)

        end

        % Value changed function: CA1Switch
        function CA1SwitchValueChanged(app, event)
            app.CA1or2switch =true;
            startupFcn(app, app.Xtable, 'omega',app.SliderOmega.Value,  'alpha',app.SliderAlpha.Value, ...
                'showRowPoints', app.ChkBoxshowRowPoints.Value, 'showRowNames', app.ChkBoxshowRowNames.Value,...
                'showArrows',app.ChkBoxshowArrows.Value,'standardize',app.standardize,'bsb',app.bsb,...
                'RowPointsProp2OrthDist',app.ChkBoxRowPointsProp2Dist.Value,'BDPslider',app.SliderBDP.Value, ...
                'FSslider',app.SliderFS.Value)

        end

        % Value changed function: CA2Switch
        function CA2SwitchValueChanged(app, event)
            app.CA1or2switch =true;
            startupFcn(app, app.Xtable, 'omega',app.SliderOmega.Value,  'alpha',app.SliderAlpha.Value, ...
                'showRowPoints', app.ChkBoxshowRowPoints.Value, 'showRowNames', app.ChkBoxshowRowNames.Value,...
                'showArrows',app.ChkBoxshowArrows.Value,'standardize',app.standardize,'bsb',app.bsb,...
                'RowPointsProp2OrthDist',app.ChkBoxRowPointsProp2Dist.Value,'BDPslider',app.SliderBDP.Value, ...
                'FSslider',app.SliderFS.Value)

        end

        % Value changed function: ChkBoxChangeSign
        function ChkBoxChangeSignValueChanged(app, event)
            if app.ChkBoxChangeSign.Value == true
                app.ChangesignPanel.Visible ="on";
            else
                app.ChangesignPanel.Visible ="off";
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FSDAdynamicinteractivebiplotUIFigure = uifigure('Visible', 'off');
            app.FSDAdynamicinteractivebiplotUIFigure.Name = 'FSDA dynamic interactive biplot';
            app.FSDAdynamicinteractivebiplotUIFigure.CloseRequestFcn = createCallbackFcn(app, @FSDAdynamicinteractivebiplotUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.FSDAdynamicinteractivebiplotUIFigure);
            xlabel(app.UIAxes, 'PC1')
            ylabel(app.UIAxes, 'PC2')
            app.SliderLabel = uilabel(app.FSDAdynamicinteractivebiplotUIFigure);
            app.SliderLabel.Text = 'ω';
            app.SliderOmega = uislider(app.FSDAdynamicinteractivebiplotUIFigure);
            app.SliderOmega.Limits = [0 1];
            app.SliderOmega.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.SliderOmega.ValueChangedFcn = createCallbackFcn(app, @SliderOmegaValueChanged, true);
            app.SliderOmega.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.SliderOmega.Value = 0.7;
            app.Label = uilabel(app.FSDAdynamicinteractivebiplotUIFigure);
            app.Label.Text = 'α';
            app.SliderAlpha = uislider(app.FSDAdynamicinteractivebiplotUIFigure);
            app.SliderAlpha.Limits = [0 1];
            app.SliderAlpha.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.SliderAlpha.ValueChangedFcn = createCallbackFcn(app, @SliderAlphaValueChanged, true);
            app.SliderAlpha.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.SliderAlpha.Value = 0.3;
            app.ChkBoxshowRowPoints = uicheckbox(app.FSDAdynamicinteractivebiplotUIFigure);
            app.ChkBoxshowRowPoints.ValueChangedFcn = createCallbackFcn(app, @ChkBoxshowRowPointsValueChanged, true);
            app.ChkBoxshowRowPoints.Tooltip = {'Show the coordinates (scores) of each row with respect to the first two PCs. The kind of score (unstandardized, standardized or in between) depends on the ω and α sliders on top of the window.'};
            app.ChkBoxshowRowPoints.Text = 'showRowPoints';
            app.ChkBoxshowRowPoints.Value = true;
            app.ChkBoxshowRowNames = uicheckbox(app.FSDAdynamicinteractivebiplotUIFigure);
            app.ChkBoxshowRowNames.ValueChangedFcn = createCallbackFcn(app, @ChkBoxshowRowNamesValueChanged, true);
            app.ChkBoxshowRowNames.Tooltip = {'Show the labels (RowNames) of the scores.'};
            app.ChkBoxshowRowNames.Text = 'showRowNames';
            app.ChkBoxshowArrows = uicheckbox(app.FSDAdynamicinteractivebiplotUIFigure);
            app.ChkBoxshowArrows.ValueChangedFcn = createCallbackFcn(app, @ChkBoxshowArrowsValueChanged, true);
            app.ChkBoxshowArrows.Tooltip = {'Show or hide the arrows associated to the correlations among the original variables and among the variables and the two PCs axes. The length of the arrows depends on the ω and α sliders on top of the window.'};
            app.ChkBoxshowArrows.Text = 'showArrows';
            app.ChkBoxshowArrows.Value = true;
            app.ChkBoxRowPointsProp2Dist = uicheckbox(app.FSDAdynamicinteractivebiplotUIFigure);
            app.ChkBoxRowPointsProp2Dist.ValueChangedFcn = createCallbackFcn(app, @ChkBoxRowPointsProp2DistValueChanged, true);
            app.ChkBoxRowPointsProp2Dist.Tooltip = {'If this checkbox is selected every row point has a color which is proportional to its orthogonal distance to the subspaced spanned by the first two principal components. Units in dark blue have a very good representation while units in red have a large orthogonal distance and are not represented well.'};
            app.ChkBoxRowPointsProp2Dist.Text = 'color prop to orthogonal distance';
            app.TabGroup = uitabgroup(app.FSDAdynamicinteractivebiplotUIFigure);
            app.MCD = uitab(app.TabGroup);
            app.MCD.Title = 'MCD';
            app.SliderBDPLabel = uilabel(app.MCD);
            app.SliderBDPLabel.Text = 'bdp';
            app.SliderBDP = uislider(app.MCD);
            app.SliderBDP.Limits = [0 0.5];
            app.SliderBDP.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5];
            app.SliderBDP.MajorTickLabels = {'0.5', '0.4', '0.3', '0.2', '0.1', '0'};
            app.SliderBDP.ValueChangedFcn = createCallbackFcn(app, @SliderBDPValueChanged, true);
            app.SliderBDP.MinorTicks = [0 0.05 0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5];
            app.SliderBDP.Tooltip = {'Select the value of breakdown point you wish to use in otder to compute the MCD. The units flagged as outliers at the 1 per cent simultaneous confidence level are shown in the plot with filled circle'};
            app.SliderBDP.Value = 0.5;
            app.ForwardSearch = uitab(app.TabGroup);
            app.ForwardSearch.Title = 'Forward Search';
            app.SliderFSLabel = uilabel(app.ForwardSearch);
            app.SliderFSLabel.Text = {'Subset size/n'; ''};
            app.SliderFS = uislider(app.ForwardSearch);
            app.SliderFS.Limits = [-1 10000000];
            app.SliderFS.MajorTicks = [];
            app.SliderFS.ValueChangedFcn = createCallbackFcn(app, @SliderFSValueChanged, true);
            app.SliderFS.MinorTicks = [-1 99999 199999 299999 399999 499999 599999 699999 799999 899999 999999 1099999 1199999 1299999 1399999 1499999 1599999 1699999 1799999 1899999 1999999 2099999 2199999 2299999 2399999 2499999 2599999 2699999 2799999 2899999 2999999 3099999 3199999 3299999 3399999 3499999 3599999 3699999 3799999 3899999 3999999 4099999 4199999 4299999 4399999 4499999 4599999 4699999 4799999 4899999 4999999 5099999 5199999 5299999 5399999 5499999 5599999 5699999 5799999 5899999 5999999 6099999 6199999 6299999 6399999 6499999 6599999 6699999 6799999 6899999 6999999 7099999 7199999 7299999 7399999 7499999 7599999 7699999 7799999 7899999 7999999 8099999 8199999 8299999 8399999 8499999 8599999 8699999 8799999 8899999 8999999 9099999 9199999 9299999 9399999 9499999 9599999 9699999 9799999 9899999 10000000];
            app.SliderFS.Tooltip = {'Select the value of subset size you wish to use in order to compute CA for that subset size. The units outside the subset  are shown in the plot with filled circles.'};
            app.SliderFS.Value = 10000000;
            app.ChangesignPanel = uipanel(app.FSDAdynamicinteractivebiplotUIFigure);
            app.ChangesignPanel.Tooltip = {'Revert the direction of the first or second principal component'};
            app.ChangesignPanel.Title = 'Change sign';
            app.ChangesignPanel.Visible = 'off';
            app.CA2SwitchLabel = uilabel(app.ChangesignPanel);
            app.CA2SwitchLabel.Text = 'PC2';
            app.CA2Switch = uiswitch(app.ChangesignPanel, 'slider');
            app.CA2Switch.ItemsData = [false true];
            app.CA2Switch.ValueChangedFcn = createCallbackFcn(app, @CA2SwitchValueChanged, true);
            app.CA2Switch.Value = false;
            app.CA1SwitchLabel = uilabel(app.ChangesignPanel);
            app.CA1SwitchLabel.Text = 'PC1';
            app.CA1Switch = uiswitch(app.ChangesignPanel, 'slider');
            app.CA1Switch.ItemsData = [false true];
            app.CA1Switch.ValueChangedFcn = createCallbackFcn(app, @CA1SwitchValueChanged, true);
            app.CA1Switch.Value = false;
            app.ChkBoxChangeSign = uicheckbox(app.FSDAdynamicinteractivebiplotUIFigure);
            app.ChkBoxChangeSign.ValueChangedFcn = createCallbackFcn(app, @ChkBoxChangeSignValueChanged, true);
            app.ChkBoxChangeSign.Tooltip = {'Open the panel which allows the user to change the sign of the 1st or 2nd eigenvector. Note that the biplot  is changed, and also the plot of the correlations, between the variables and the PCs, but the output structure in the Workspace does not change.'};
            app.ChkBoxChangeSign.Text = 'Show change sign panel';
            % Show the figure after all components are created
            app.FSDAdynamicinteractivebiplotUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = biplotAPP(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FSDAdynamicinteractivebiplotUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FSDAdynamicinteractivebiplotUIFigure)
        end
    end
end