classdef geoplotAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FSDAInteractivegeomapUIFigure  matlab.ui.Figure
        MainLabel                      matlab.ui.control.Label
        geoPanel                       matlab.ui.container.Panel
        Panel                          matlab.ui.container.Panel
        ColorbasedonDropDown           matlab.ui.control.DropDown
        ColorbasedonDropDownLabel      matlab.ui.control.Label
        GeographiccoordinatesCheckBox  matlab.ui.control.CheckBox
        ShowlabelsCheckBox             matlab.ui.control.CheckBox
        MapTypeDropDown                matlab.ui.control.DropDown
        MapTypeDropDownLabel           matlab.ui.control.Label
        RevertcolorSwitch              matlab.ui.control.Switch
        RevertcolorSwitchLabel         matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Property5 % Description
    end

    properties (Access = public)
        Xtable='';
        score='';
        Xgeotable='';
        proj='';
        varNames='';
        bsb='';

    end

    methods (Access = public)

    end

    methods (Access = private)

        function  main(app)
            rownames=app.Xtable.Properties.RowNames;
            n=size(app.Xtable,1);


            % % Specify the variable for which the colors have to be set
            % if app.SwitchcolortoPC2.ValueIndex == 2
            %     colorIntensity = app.score(:,2);
            %     strForColorBar='Color based on second PC';
            %
            % else
            %     colorIntensity = app.score(:,1);
            %     strForColorBar='Color based on first PC';
            % end

            colorIntensity=app.score(:,app.ColorbasedonDropDown.Value);
            strForColorBar="Color based on "+ app.varNames(app.ColorbasedonDropDown.Value);

            if app.RevertcolorSwitch.ValueIndex==2
                colorIntensity=-colorIntensity;
            end

            % Define a colorbar from green to red with 50 colors
            numColors=50;
            customGradient = interp1([0;1],[0 1 0; 1 0 0],linspace(0,1,numColors));


            % Show or not in geographical coordinates
            if app.GeographiccoordinatesCheckBox.Value == true
                geocoord = true;
            else
                geocoord = false;
            end

            if geocoord == true
                app.geoPanel.Visible = "on";
                app.MapTypeDropDown.Visible= "on";
                app.MapTypeDropDownLabel.Visible ="on";


                delete(app.geoPanel.Children)
                ga=geoaxes(app.geoPanel);
                % Add to the geotable the Variable which contains the colors
                
                app.Xgeotable.ColorVar=colorIntensity(:);
                
                hg=geoplot(ga,app.Xgeotable,'ColorVariable','ColorVar');
                % Find full color scale of colorIntensity
                fullMin = min(colorIntensity);
                fullMax = max(colorIntensity);

                colormap(ga,customGradient);
                h=colorbar(ga);
                h.Label.String=strForColorBar;
                value = app.MapTypeDropDown.Value;
                basemaps = ["satellite" "streets" "streets-light" "streets-dark" ...
                    "landcover" "darkwater" "grayland" "bluegreen" ...
                    "grayterrain" "colorterrain"];
                geobasemap(ga,basemaps(value))

                % Lock color limit of axes
                ax = hg.Parent;      
                clim(ax,[fullMin fullMax])     

                notbsb=setdiff(1:n,app.bsb);
                hg.SourceTable.ColorVar(notbsb)=NaN;

     
                if app.ShowlabelsCheckBox.Value == true
                    % Add labels in the centroids of the shapes
                    % estract XY pairs coords of enclosing polygons for each row of input table
                    Xtab= geotable2table(app.Xgeotable, ["X" "Y"]);
                    % Obtain the average latitute and longitude for each row
                    warning('off','map:shapefile:unsupportedType')

                    arrlonglat=zeros(n,2);

                    % Check the type of coordinates we have
                    try
                        projinv(app.proj, Xtab.X{1},Xtab.Y{1});
                        satcoord=true;
                    catch
                        satcoord=false;
                    end

                    for i = 1:n
                        if satcoord==true
                            [lat, lon] = projinv(app.proj, Xtab.X{i},Xtab.Y{i});  % Convert to lat/lon
                        else
                            lat=Xtab.X{i};
                            lon=Xtab.Y{i};
                        end
                        
                        %   arrlonglat(i,1:2)=[mean(lat,'omitmissing') mean(lon,'omitmissing')];
                        % Improved code to define the centroid
                        [meanlat,meanlon]=centroid(polyshape([lat' lon']));
                        arrlonglat(i,1:2)=[meanlat,meanlon];
                     
                    end

                    % Show the labels using geographical coordinates
                    text(ga,arrlonglat(app.bsb,1), arrlonglat(app.bsb,2),rownames(app.bsb))
                end

            else % Plot without using geographical coordinates
                app.geoPanel.Visible = "off";
                app.MapTypeDropDown.Visible="off";
                app.MapTypeDropDownLabel.Visible ="off";

                cla(app.UIAxes, 'reset')

                fc=customGradient(round(rescale(colorIntensity,1,numColors)),:);

                % Check if mapshow can be called (Display map data without
                % projection)
                % otherwise call geoshow (Display map latitude and
                % longitude data)
                GT=app.Xgeotable;
                typeSHape=GT.Properties.VariableTypes(string(GT.Properties.VariableNames)=="Shape");
                if ismember(typeSHape,["mappointshape", "maplineshape" "mappolyshape"])
                    polygon=mapshow(app.UIAxes,app.Xgeotable, "FaceAlpha",0.9,"FaceColor","none");
                    rev=false;
                else
                    polygon=geoshow(app.UIAxes,app.Xgeotable, "FaceAlpha",0.9,"FaceColor","none");
                    rev=true;
                end
                for i=app.bsb % 1:n
                    set(polygon.Children(i), 'FaceColor', fc(i,:),"FaceAlpha",0.9);
                end
                colormap(app.UIAxes,customGradient);
                rang=[min(colorIntensity) max(colorIntensity)];
                h=colorbar(app.UIAxes);
                h.Label.String=strForColorBar;
                soglie=linspace(min(rang),max(rang),6);
                valori=(soglie-min(soglie))/(max(soglie)-min(soglie));
                h.Ticks=valori;
                h.TickLabels=string(soglie);

                if app.ShowlabelsCheckBox.Value == true

                    % Add labels in the centroids of the shapes
                    arrlonglat=zeros(n,2);
                    % extract XY pairs coords (that is latitude and
                    % longitude) of enclosing polygons for each row of
                    % input table
                    Xtab= geotable2table(app.Xgeotable, ["X" "Y"]);
                    warning('off','MATLAB:polyshape:repairedBySimplify')

                    for i=app.bsb  % 1:n
                        % first we create polygonal objects
                        polObj= polyshape(Xtab.X{i,1}, ...
                            Xtab.Y{i,1});
                        [centX,centY]=centroid(polObj);
                        % Store centroids
                        if rev==true
                            arrlonglat(i,1:2)=[centY centX];
                        else
                            arrlonglat(i,1:2)=[centX centY];
                        end    
                    end
                    text(app.UIAxes,arrlonglat(app.bsb,1), arrlonglat(app.bsb,2),rownames(app.bsb))
                end
                hold(app.UIAxes,'off')
            end
        end
    end
    %                  GeographicAxes     FaceColor
    %    geoplot            yes             no (yes if mapping toolbox is
    %                                           installed)
    %    geoscatter         yes             no
    %    wmpolygon           no             yes       (to be removed)
    %     mapshow            no             yes
    %     geoshow            no             yes
    % geodensityplot         yes             no
    %
    % To call geoplot or  mapshow
    %   mappointshape, maplineshape, or mappolyshape 

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Xtable, score, ShapeFile, bsb)
            
                [n,p]=size(Xtable);
                
            if ~istable(Xtable)
                Xtable=array2table(Xtable,"RowNames","r"+(1:n),"VariableNames","X"+(1:p));
            end

            if isempty(bsb)
                bsb=1:n;
            elseif islogical(bsb)
                seq=1:n;
                bsb=seq(bsb);
            end

            if isgeotable(ShapeFile)
                app.Xgeotable=ShapeFile;
            elseif ischar(ShapeFile) || isstring(ShapeFile)
                app.Xgeotable = readgeotable(ShapeFile);
            else
                error('FSDA:geoplotAPP:WrongInput','ShapeFile must be a char or a string or a geotable')
            end
            % Not necessary to call shapeinfo
            %{
            info = shapeinfo(ShapeFile);
            app.proj = info.CoordinateReferenceSystem;
            %}


            % score can be a scalar number (in this case it refers to a particular
            % column for example 3) a scalar string or char (in this case
            % it contains the name of the variable inside Xtable to use).
            % It can be a matrix with two columns (in this last case we
            % assume that these are the first two principal components)


            % Try to undestand type of coordinate systems. The geographic coordinate
            % systems are based on latitude
            % and longitude coordinates. The projected coordinate systems,
            % represent the projection of a geographic coordinate
            % system on a plane and use linear measures (like feet or
            % meters) for coordinates.
            % https://www.hec.usace.army.mil/confluence/cwmsdocs/cwmsum/3.1.1/setting-up-the-coordinate-system/coordinate-system-types
            try
                app.proj = app.Xgeotable.Shape.ProjectedCRS;
            catch
                app.proj=[];
                % app.proj= projcrs(32632);
            end

            if isscalar(score) || ischar(score)
                % Populate the drop down variable
                app.varNames=Xtable.Properties.VariableNames;
                if isnumeric(score)
                    valDropDown=score;
                else
                    valDropDown=find(string(app.varNames)==string(score));
                end

                score=Xtable{:,:};
            else
                app.varNames="PC"+(1:size(score,2));
                valDropDown=1;
                app.MainLabel.Text="Interactive geomap based on (robust) PC1 or PC2";
            end
            app.ColorbasedonDropDown.Items=app.varNames;
            app.ColorbasedonDropDown.ItemsData=1:length(app.varNames);
            app.ColorbasedonDropDown.Value=valDropDown;


            % Store Xtable
            app.Xtable=Xtable;
            app.score=score;
            app.bsb=bsb;
            main(app)

        end

        % Value changed function: ShowlabelsCheckBox
        function ShowlabelsCheckBoxValueChanged(app, event)
            % value = app.ShowlabelsCheckBox.Value;
            main(app)
        end

        % Value changed function: RevertcolorSwitch
        function RevertcolorSwitchValueChanged(app, event)
            main(app)
        end

        % Value changed function: MapTypeDropDown
        function MapTypeDropDownValueChanged(app, event)
            main(app)
        end

        % Value changed function: GeographiccoordinatesCheckBox
        function GeographiccoordinatesCheckBoxValueChanged(app, event)
            main(app)
        end

        % Value changed function: ColorbasedonDropDown
        function ColorbasedonDropDownValueChanged(app, event)
            main(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FSDAInteractivegeomapUIFigure = uifigure('Visible', 'off');
            app.FSDAInteractivegeomapUIFigure.Name = 'FSDA: Interactive geomap';
            app.UIAxes = uiaxes(app.FSDAInteractivegeomapUIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Panel = uipanel(app.FSDAInteractivegeomapUIFigure);
            app.RevertcolorSwitchLabel = uilabel(app.Panel);
            app.RevertcolorSwitchLabel.Text = 'Revert color';
            app.RevertcolorSwitch = uiswitch(app.Panel, 'slider');
            app.RevertcolorSwitch.ValueChangedFcn = createCallbackFcn(app, @RevertcolorSwitchValueChanged, true);
            app.MapTypeDropDownLabel = uilabel(app.Panel);
            app.MapTypeDropDownLabel.Text = 'Map Type';
            app.MapTypeDropDown = uidropdown(app.Panel);
            app.MapTypeDropDown.Items = {'"satellite"', '"streets"', '"streets-light"', '"streets-dark"', '"landcover"', '"darkwater"', '"grayland"', '"bluegreen" ', '"grayterrain"', '"colorterrain'};
            app.MapTypeDropDown.ItemsData = [1 2 3 4 5 6 7 8 9 10];
            app.MapTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @MapTypeDropDownValueChanged, true);
            app.MapTypeDropDown.Tooltip = {'Type of map to use. Note that this drop down is visbile just if Geografic coordinates is selected.'};
            app.MapTypeDropDown.Value = 3;
            app.ShowlabelsCheckBox = uicheckbox(app.Panel);
            app.ShowlabelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowlabelsCheckBoxValueChanged, true);
            app.ShowlabelsCheckBox.Text = 'Show labels';
            app.GeographiccoordinatesCheckBox = uicheckbox(app.Panel);
            app.GeographiccoordinatesCheckBox.ValueChangedFcn = createCallbackFcn(app, @GeographiccoordinatesCheckBoxValueChanged, true);
            app.GeographiccoordinatesCheckBox.Tooltip = {'Use geogrphic axes based on latitute and longitude or simpl use the coordinates in the original ShapeFile'};
            app.GeographiccoordinatesCheckBox.Text = 'Geographic coordinates';
            app.GeographiccoordinatesCheckBox.Value = true;
            app.ColorbasedonDropDownLabel = uilabel(app.Panel);
            app.ColorbasedonDropDownLabel.Text = 'Color  based on ';
            app.ColorbasedonDropDown = uidropdown(app.Panel);
            app.ColorbasedonDropDown.Items = {};
            app.ColorbasedonDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorbasedonDropDownValueChanged, true);
            app.ColorbasedonDropDown.Tooltip = {'Type of map to use. Note that this drop down is visbile just if Geografic coordinates is selected.'};
            app.ColorbasedonDropDown.Value = {};
            app.geoPanel = uipanel(app.FSDAInteractivegeomapUIFigure);
            app.MainLabel = uilabel(app.FSDAInteractivegeomapUIFigure);
            app.MainLabel.Text = 'Interactive geomap based on selected variables';
            % Show the figure after all components are created
            app.FSDAInteractivegeomapUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = geoplotAPP(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FSDAInteractivegeomapUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FSDAInteractivegeomapUIFigure)
        end
    end
end