classdef HelpGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        TabPreamble                   matlab.ui.container.Tab
        LabelEditField                matlab.ui.control.Label
        Name                          matlab.ui.control.EditField
        LabelTextArea                 matlab.ui.control.Label
        Purpose                       matlab.ui.control.TextArea
        LabelTextArea2                matlab.ui.control.Label
        Description                   matlab.ui.control.TextArea
        Label2                        matlab.ui.control.Label
        Label3                        matlab.ui.control.Label
        Label4                        matlab.ui.control.Label
        TabReqArgs                    matlab.ui.container.Tab
        LabelListBox                  matlab.ui.control.Label
        InpArgsListBox                matlab.ui.control.ListBox
        LabelTextArea3                matlab.ui.control.Label
        ShortDesc                     matlab.ui.control.TextArea
        Label                         matlab.ui.control.Label
        LongDesc                      matlab.ui.control.TextArea
        Label5                        matlab.ui.control.Label
        Label6                        matlab.ui.control.Label
        TypeIndication                matlab.ui.control.TextArea
        Label7                        matlab.ui.control.Label
        Label8                        matlab.ui.control.Label
        RequiredInput                 matlab.ui.control.CheckBox
        Label9                        matlab.ui.control.Label
        LabelEditField2               matlab.ui.control.Label
        Example                       matlab.ui.control.EditField
        AddInpArg                     matlab.ui.control.Button
        DelInpArg                     matlab.ui.control.Button
        Label10                       matlab.ui.control.Label
        DataType                      matlab.ui.control.EditField
        InpArgsUITable                matlab.ui.control.Table
        InpStructure                  matlab.ui.control.CheckBox
        Label5_5                      matlab.ui.control.Label
        InpArgsZoom                   matlab.ui.control.TextArea
        InpArgsStructurevalueLabel    matlab.ui.control.Label
        InpArgsListBoxStruct          matlab.ui.control.ListBox
        InpArgsStructuredescriptionLabel  matlab.ui.control.Label
        TabOptArgs                    matlab.ui.container.Tab
        Label7_2                      matlab.ui.control.Label
        Label8_2                      matlab.ui.control.Label
        Label9_2                      matlab.ui.control.Label
        AddOptArg                     matlab.ui.control.Button
        DelOptArg                     matlab.ui.control.Button
        ListofOptionalInputargumentsLabel  matlab.ui.control.Label
        OptArgsListBox                matlab.ui.control.ListBox
        ShortDesc_2Label              matlab.ui.control.Label
        OptArgsShortDesc              matlab.ui.control.TextArea
        LongDesc_2Label               matlab.ui.control.Label
        OptArgsLongDesc               matlab.ui.control.TextArea
        TypeIndication_2Label         matlab.ui.control.Label
        OptArgsTypeIndication         matlab.ui.control.TextArea
        Example_2Label                matlab.ui.control.Label
        OptArgsExample                matlab.ui.control.EditField
        DataType_2Label               matlab.ui.control.Label
        OptArgsDataType               matlab.ui.control.EditField
        OptArgsUITable                matlab.ui.control.Table
        OptStructure                  matlab.ui.control.CheckBox
        Label5_6                      matlab.ui.control.Label
        TabOutArgs                    matlab.ui.container.Tab
        OutputArgumentsLabel          matlab.ui.control.Label
        OutArgsListBox                matlab.ui.control.ListBox
        OutArgsTypeIndicationLabel    matlab.ui.control.Label
        OutArgsLongDescLabel          matlab.ui.control.Label
        OutArgsShortDescLabel         matlab.ui.control.Label
        OutArgsShortDescLabel1        matlab.ui.control.Label
        OutArgsShortDesc              matlab.ui.control.TextArea
        OutArgsLongDescLabel1         matlab.ui.control.Label
        OutArgsLongDesc               matlab.ui.control.TextArea
        OutArgsTypeIndicationLabel1   matlab.ui.control.Label
        OutArgsTypeIndication         matlab.ui.control.TextArea
        OutArgsUITable                matlab.ui.control.Table
        Label5_2                      matlab.ui.control.Label
        OutStructure                  matlab.ui.control.CheckBox
        AddOutArg                     matlab.ui.control.Button
        DelOutArg                     matlab.ui.control.Button
        Label7_4                      matlab.ui.control.Label
        TabMoreAbout                  matlab.ui.container.Tab
        MoreAboutLabel                matlab.ui.control.Label
        MoreAbout                     matlab.ui.control.TextArea
        AcknowledgementsLabel         matlab.ui.control.Label
        Acknowledgements              matlab.ui.control.TextArea
        ReferencesLabel               matlab.ui.control.Label
        References                    matlab.ui.control.TextArea
        SeeAlsoListBoxLabel           matlab.ui.control.Label
        SeeAlsoListBox                matlab.ui.control.ListBox
        AddSeeAlsoArg                 matlab.ui.control.Button
        DelSeeAlsoArg                 matlab.ui.control.Button
        TabExamples                   matlab.ui.container.Tab
        Label5_3                      matlab.ui.control.Label
        Label7_5                      matlab.ui.control.Label
        Label8_3                      matlab.ui.control.Label
        ExtoExecute                   matlab.ui.control.CheckBox
        AddEx                         matlab.ui.control.Button
        DelEx                         matlab.ui.control.Button
        MatlabcodewithcommentsLabel   matlab.ui.control.Label
        ExMatlabCode                  matlab.ui.control.TextArea
        ExampledescriptionLabel       matlab.ui.control.Label
        ExDescription                 matlab.ui.control.TextArea
        TitleoftheexampleLabel        matlab.ui.control.Label
        ExListBox                     matlab.ui.control.ListBox
        TabExtraExamples              matlab.ui.container.Tab
        Label5_4                      matlab.ui.control.Label
        Label7_6                      matlab.ui.control.Label
        Label8_4                      matlab.ui.control.Label
        ExtraExtoExecute              matlab.ui.control.CheckBox
        AddExtraEx                    matlab.ui.control.Button
        DelExtraEx                    matlab.ui.control.Button
        ExMatlabCode_2Label           matlab.ui.control.Label
        ExtraExMatlabCode             matlab.ui.control.TextArea
        ExtraExampledescriptionLabel  matlab.ui.control.Label
        ExtraExDescription            matlab.ui.control.TextArea
        TitleoftheextraexampleLabel   matlab.ui.control.Label
        ExtraExListBox                matlab.ui.control.ListBox
        SavechangestoXMLfileButton    matlab.ui.control.Button
    end

    
    properties (Access = public)
        out % This is the output of function publishFS. This variable will be shared across functions
    end
    
    methods (Access = private)
        
        function fillInputArgs(app,i)
            %app.ShortDesc.Value=app.out.InpArgs{i,2};
            
            
            if ~isempty(app.out.InpArgs{i,2})
                app.ShortDesc.Value=sprintf('%s\n',app.out.InpArgs{i,2});
            else
                app.ShortDesc.Value='';
            end
            
            if ~isempty(app.out.InpArgs{i,3})
                app.TypeIndication.Value=app.out.InpArgs{i,3};
            else
                app.TypeIndication.Value='';
            end
            if ~isempty(app.out.InpArgs{i,4})
                app.LongDesc.Value=sprintf('%s\n',app.out.InpArgs{i,4});
            else
                app.LongDesc.Value='';
            end
            
            if ~isempty(app.out.InpArgs{i,5})
                app.Example.Value=app.out.InpArgs{i,5};
            else
                app.Example.Value='';
            end
            if ~isempty(app.out.InpArgs{i,6})
                app.DataType.Value=app.out.InpArgs{i,6};
            else
                app.DataType.Value='';
            end
            if strcmp(app.out.InpArgs{i,7},'1')
                app.RequiredInput.Value=true;
            else
                app.RequiredInput.Value=false;
            end
            
            if ~isempty(app.out.InpArgs{i,8})
                state='on';
                app.InpArgsUITable.Visible=state;
                app.InpArgsListBoxStruct.Visible=state;
                app.InpArgsZoom.Visible=state;
                app.InpArgsStructurevalueLabel.Visible=state;
                app.InpArgsStructuredescriptionLabel.Visible=state;
                
                % if iscell(app.out.InpArgs(i,8))
                TAB=app.out.InpArgs{i,8};
                app.InpArgsUITable.Data=TAB;
                app.InpStructure.Value=true;
                if ~isempty(TAB{1,2})
                    app.InpArgsZoom.Value=TAB{1,2};
                    %try
                    boo=cellfun(@isempty,TAB(:,1));
                    app.InpArgsListBoxStruct.Items=TAB(~boo,1)';
                    %catch
                    %    ddd=1;
                    %end
                else
                    app.InpArgsListBoxStruct.Items={''};
                    app.InpArgsZoom.Value='';
                end
                
                
            else
                %
                app.InpStructure.Value=false;
                state='off';
                app.InpArgsUITable.Data='';
                app.InpArgsListBoxStruct.Items={''};
                app.InpArgsZoom.Value='';
                
                app.InpArgsUITable.Visible=state;
                app.InpArgsListBoxStruct.Visible=state;
                app.InpArgsZoom.Visible=state;
                app.InpArgsStructurevalueLabel.Visible=state;
                app.InpArgsStructuredescriptionLabel.Visible=state;
            end
            
        end


        function fillOptArgs(app,i)
            
            if ~isempty(app.out.OptArgs)
                
                if ~isempty(app.out.OptArgs{i,2})
                    app.OptArgsShortDesc.Value=app.out.OptArgs{i,2};
                else
                    app.OptArgsShortDesc.Value='';
                end
                
                if ~isempty(app.out.OptArgs{i,3})
                    app.OptArgsTypeIndication.Value=app.out.OptArgs{i,3};
                else
                    app.OptArgsTypeIndication.Value='';
                end
                
                if ~isempty(app.out.OptArgs{i,4})
                    app.OptArgsLongDesc.Value=app.out.OptArgs{i,4};
                else
                    app.OptArgsLongDesc.Value='';
                end
                
                if ~isempty(app.out.OptArgs{i,5})
                    app.OptArgsExample.Value=app.out.OptArgs{i,5};
                else
                    app.OptArgsExample.Value='';
                end
                if ~isempty(app.out.OptArgs{i,6})
                    app.OptArgsDataType.Value=app.out.OptArgs{i,6};
                else
                    app.OptArgsDataType.Value='';
                end
                
                if ~isempty(app.out.OptArgs{i,7})
                    app.OptArgsUITable.Data=app.out.OptArgs{i,7};
                    app.OptStructure.Value=true;
                else
                    app.OptArgsUITable.Data='';
                    app.OptStructure.Value=false;
                end
            end
        end
        
        function fillOutArgs(app,i)
            %app.OutArgsShortDesc.Value='';
            %app.OutArgsTypeIndication.Value='';
            %app.OutArgsLongDesc.Value='';
            if ~isempty(app.out.OutArgs{i,2})
                app.OutArgsShortDesc.Value=app.out.OutArgs{i,2};
            else
                app.OutArgsShortDesc.Value='';
            end
            if ~isempty(app.out.OutArgs{i,3})
                app.OutArgsTypeIndication.Value=app.out.OutArgs{i,3};
            else
                app.OutArgsTypeIndication.Value='';
            end
            if ~isempty(app.out.OutArgs{i,4})
                app.OutArgsLongDesc.Value=app.out.OutArgs{i,4};
            else
                app.OutArgsLongDesc.Value='';
            end
            
            if ~isempty(app.out.OutArgs{i,5})
                app.OutStructure.Value=true;
                app.OutArgsUITable.Visible='on';
                app.OutArgsUITable.Data=app.out.OutArgs{i,5};
            else
                app.OutStructure.Value=false;
                app.OutArgsUITable.Visible='off';
                app.OutStructure.Value=false;
            end
        end
        
        function fillExArgs(app,i)
            if ~isempty(app.out.Ex{i,2})
                app.ExDescription.Value=app.out.Ex{i,2};
            else
                app.ExDescription.Value='';
            end
            
            if ~isempty(app.out.Ex{i,3})
                app.ExMatlabCode.Value=app.out.Ex{i,3};
            else
                app.ExMatlabCode.Value='';
            end
            
            if strcmp(app.out.Ex{i,4},'1')
                app.ExtoExecute.Value=true;
            else
                app.ExtoExecute.Value=false;
            end
        end
        
        function fillExtraExArgs(app,i)
            if ~isempty(app.out.ExtraEx{i,2})
                app.ExtraExDescription.Value=app.out.ExtraEx{i,2};
            else
                app.ExtraExDescription.Value='';
            end
            if ~isempty(app.out.ExtraEx{i,3})
                app.ExtraExMatlabCode.Value=app.out.ExtraEx{i,3};
            else
                app.ExtraExMatlabCode.Value='';
            end
            
            if strcmp(app.out.ExtraEx{i,4},'1')
                app.ExtraExtoExecute.Value=true;
            else
                app.ExtraExtoExecute.Value=false;
            end
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Run publishFS
            % app.out=publishFS('tclust.m','evalCode',false,'write2file',false');
            % app.out=publishFS('FSM.m','evalCode',false,'write2file',false');
            % assignin('caller','InputStructForHelpGUI',InputStructForHelpGUI)
            
            [FSDAroot]=fileparts(which('docsearchFS.m'));
            
            fsep=filesep;
            fullpathFileName=[FSDAroot fsep 'helpfiles' fsep 'XML' fsep 'InputStructForHelpGUI'];
            load(fullpathFileName,'InputStructForHelpGUI')
            
            app.out=InputStructForHelpGUI;
            
            % preamble tab
            app.Name.Value=app.out.titl;
            app.Purpose.Value=app.out.purpose;
            app.Description.Value=app.out.description;
            
            % TabReqArgs Input Arguments tab
            app.InpArgsListBox.Items=app.out.InpArgs(:,1)';
            app.InpArgsListBox.ItemsData=1:size(app.out.InpArgs,1);
            
            i=1;
            fillInputArgs(app,i)
            
            
            % Optional (Name/Pairs) Input Arguments tab
            app.OptArgsListBox.Items=app.out.OptArgs(:,1)';
            app.OptArgsListBox.ItemsData=1:size(app.out.OptArgs,1);
            fillOptArgs(app,i)
            %
            %             if ~isempty(app.out.OptArgs{i,2})
            %                 app.OptArgsShortDesc.Value=app.out.OptArgs{i,2};
            %             end
            %             if ~isempty(app.out.OptArgs{i,3})
            %                 app.OptArgsTypeIndication.Value=app.out.OptArgs{i,3};
            %             end
            %             if ~isempty(app.out.OptArgs{i,4})
            %                 app.OptArgsLongDesc.Value=app.out.OptArgs{i,4};
            %             end
            %
            %             if ~isempty(app.out.OptArgs{i,5})
            %                 app.OptArgsExample.Value=app.out.OptArgs{i,5};
            %             end
            %             if ~isempty(app.out.OptArgs{i,6})
            %                 app.OptArgsDataType.Value=app.out.OptArgs{i,6};
            %             end
            %             if ~isempty(app.out.OptArgs{i,7})
            %                 app.OptArgsUITable.Data=app.out.OptArgs{i,7};
            %                 app.OptStructure.Value=true;
            %             end
            
            % Output Arguments tab
            app.OutArgsListBox.Items=app.out.OutArgs(:,1)';
            app.OutArgsListBox.ItemsData=1:size(app.out.OutArgs,1);
            
            if ~isempty(app.out.OutArgs{i,2})
                app.OutArgsShortDesc.Value=app.out.OutArgs{i,2};
            end
            if ~isempty(app.out.OutArgs{i,3})
                app.OutArgsTypeIndication.Value=app.out.OutArgs{i,3};
            end
            if ~isempty(app.out.OutArgs{i,4})
                app.OutArgsLongDesc.Value=app.out.OutArgs{i,4};
            end
            
            if ~isempty(app.out.OutArgs{i,5})
                app.OutStructure.Value=true;
                app.OutArgsUITable.Visible='on';
                app.OutArgsUITable.Data=app.out.OutArgs{i,5};
            else
                app.OutArgsUITable.Visible='off';
            end
            % More About tab
            app.MoreAbout.Value=app.out.MoreAbout;
            app.Acknowledgements.Value=app.out.Acknowledgements;
            app.References.Value=app.out.References;
            
            app.SeeAlsoListBox.Items=app.out.SeeAlso';
            app.SeeAlsoListBox.ItemsData=1:length(app.out.SeeAlso);
            
            % Examples tab
            app.ExListBox.Items=app.out.Ex(:,1)';
            app.ExListBox.ItemsData=1:size(app.out.Ex,1);
            
            if strcmp(app.out.Ex{i,4},'1')
                app.ExtoExecute.Value=true;
            end
            if ~isempty(app.out.Ex{i,2})
                app.ExDescription.Value=app.out.Ex{i,2};
            end
            if ~isempty(app.out.Ex{i,3})
                app.ExMatlabCode.Value=app.out.Ex{i,3};
            end
            
            % ExtraExamples tab
            if ~isempty(app.out.ExtraEx)
                app.ExtraExListBox.Items=app.out.ExtraEx(:,1)';
                app.ExtraExListBox.ItemsData=1:size(app.out.ExtraEx,1);
                
                if strcmp(app.out.ExtraEx{i,4},'1')
                    app.ExtraExtoExecute.Value=true;
                end
                if ~isempty(app.out.ExtraEx{i,2})
                    app.ExtraExDescription.Value=app.out.ExtraEx{i,2};
                end
                if ~isempty(app.out.ExtraEx{i,3})
                    app.ExtraExMatlabCode.Value=app.out.ExtraEx{i,3};
                end
            end
        end

        % Value changed function: InpArgsListBox
        function ListBoxChanged(app, event)
            value = event.PreviousValue;
            if isempty(value)
                value=1;
            end
            % make sure there is nothing pending
            drawnow;
            % Use previous value to store modifications insice the textboxes ExMatlabCode and ExDescription
            app.out.InpArgs{value,3}=sprintf('%s\n',app.TypeIndication.Value{:});
            app.out.InpArgs{value,2}=sprintf('%s\n',app.ShortDesc.Value{:});
            app.out.InpArgs{value,4}=sprintf('%s\n',app.LongDesc.Value{:});
            if iscell(app.Example.Value)
                app.out.InpArgs{value,5}=sprintf('%s\n',app.Example.Value{:});
            else
                app.out.InpArgs{value,5}=sprintf('%s\n',app.Example.Value);
            end
            if iscell(app.DataType.Value)
                app.out.InpArgs{value,6}=sprintf('%s\n',app.DataType.Value{:});
            else
                app.out.InpArgs{value,6}=sprintf('%s\n',app.DataType.Value);
            end
            
            % Store value of textbox app.InpArgsZoom if it is not empty
            TAB=app.out.InpArgs{value,8};
            if ~cellfun(@isempty,app.InpArgsZoom.Value)
                valuestr=app.InpArgsListBoxStruct.Value;
                seq=1:length(app.InpArgsListBoxStruct.Items);
                %try
                valuestruct=seq(strcmp(valuestr,app.InpArgsListBoxStruct.Items));
                %catch
                %    ddd=1;
                %end
                TAB{valuestruct,2}=sprintf('%s\n',app.InpArgsZoom.Value{:});
                app.out.InpArgs{value,8}=TAB;
            else
                app.out.InpArgs{value,8}=app.InpArgsUITable.Data;
            end


            if app.RequiredInput.Value
                app.out.InpArgs{value,7}='1';
            else
                app.out.InpArgs{value,7}='0';
            end
            
            % Once modifications have been stored use, current value of the listbox to show the corresponding items assocaited to new selection
            
            value = app.InpArgsListBox.Value;
            fillInputArgs(app,value)
        end

        % Button pushed function: DelInpArg
        function DelInpArgButtonPushed(app, event)
            % Selected argument will be deleted from the listbox
            app.InpArgsListBox.Items(app.InpArgsListBox.Value)=[];
            
            % Selected argument will be deleted from the original cell
            if ~isempty(app.InpArgsListBox.Value)
                app.out.InpArgs(app.InpArgsListBox.Value,:)=[];
            else
                %app.out.InpArgs='';
                app.out.InpArgs=cell(1,8);
            end
            fillInputArgs(app,1)
        end

        % Button pushed function: AddInpArg
        function AddInpArgButtonPushed(app, event)
            
            % A new argument will be added to the listBox
            newValue= inputdlg('Enter new input argument',...
                'New item', [1 50]);
            if ~isempty(newValue)
                if all(cellfun(@isempty,app.out.InpArgs))
                    %if ~isempty(app.out.InpArgs)
                    app.InpArgsListBox.Items=newValue;
                    app.InpArgsListBox.ItemsData=1;
                    app.out.InpArgs(1,1)=newValue;
                else
                    app.out.InpArgs(end+1,1:end)={''};
                    app.InpArgsListBox.Items=[app.InpArgsListBox.Items newValue];
                    app.InpArgsListBox.ItemsData=1:length(app.InpArgsListBox.Items);
                    app.out.InpArgs(end,1)=newValue;
                    app.out.InpStructure.Value=0;
                    app.out.InpArgs{end,end}=[];
                    
                    % Store previous selection in the listbox
                    ev=struct;
                    ev.PreviousValue=app.InpArgsListBox.Value;
                    
                    scroll(app.InpArgsListBox,'bottom');
                    app.InpArgsListBox.Value=length(app.InpArgsListBox.Items);
                    % call routine ListBoxChanged because all the correlated elements must be deleted
                    
                    % Store current selection in the listbox
                    ev.Value=app.InpArgsListBox.Value;
                    % Call routine ListBoxChanged to store modification associated to previous values and include all empty items associated to value which has just beene added
                    ListBoxChanged(app, ev)
                end
                %else
                %    app.out.InpArgs=cell(1,8);
                %end
            end
        end

        % Value changed function: OptArgsListBox
        function OptArgsListBoxChanged(app, event)
            value = event.PreviousValue;
            
            if isempty(value)
                value=1;
            end
            
            % make sure there is nothing pending
            drawnow;
            % Use previous value to store modifications insice the textboxes ExMatlabCode and ExDescription
            app.out.OptArgs{value,3}=sprintf('%s\n',app.OptArgsTypeIndication.Value{:});
            app.out.OptArgs{value,2}=sprintf('%s\n',app.OptArgsShortDesc.Value{:});
            app.out.OptArgs{value,4}=sprintf('%s\n',app.OptArgsLongDesc.Value{:});
            if iscell(app.OptArgsExample.Value)
                app.out.OptArgs{value,5}=sprintf('%s\n',app.OptArgsExample.Value{:});
            else
                app.out.OptArgs{value,5}=sprintf('%s\n',app.OptArgsExample.Value);
            end
            if iscell(app.OptArgsDataType.Value)
                app.out.OptArgs{value,6}=sprintf('%s\n',app.OptArgsDataType.Value{:});
            else
                app.out.OptArgs{value,6}=sprintf('%s\n',app.OptArgsDataType.Value);
            end
            app.out.OptArgs{value,7}=app.OptArgsUITable.Data;
            
            % Once modifications have been stored use, current value of the listbox to show the corresponding items assocaited to new selection
            value = app.OptArgsListBox.Value;
            
            %disp(value)
            %ItemsData = app.InpArgsListBox.ItemsData;
            %disp(ItemsData)
            fillOptArgs(app,value)
        end

        % Button pushed function: AddOptArg
        function AddOptArgButtonPushed(app, event)
            % A new argument will be added to the listBox
            newValue= inputdlg('Enter new optional input argument',...
                'New item', [1 50]);
            if ~isempty(newValue)
                
                if all(cellfun(@isempty,app.out.OptArgs))
                    
                    app.OptArgsListBox.Items=newValue;
                    app.OptArgsListBox.ItemsData=1;
                    app.out.OptArgs(1,1)=newValue;
                else
                    
                    app.out.OptArgs(end+1,1:end)={''};
                    app.OptArgsListBox.Items=[app.OptArgsListBox.Items newValue];
                    app.OptArgsListBox.ItemsData=1:length(app.OptArgsListBox.Items);
                    app.out.OptArgs(end,1)=newValue;
                    
                    
                    app.out.OptArgs{end,end}=[];
                    
                    % Store previous selection in the listbox
                    ev=struct;
                    ev.PreviousValue=app.OptArgsListBox.Value;
                    
                    scroll(app.OptArgsListBox,'bottom');
                    app.OptArgsListBox.Value=length(app.OptArgsListBox.Items);
                    % call routine ListBoxChanged because all the correlated elements must be deleted
                    
                    % Store current selection in the listbox
                    ev.Value=app.OptArgsListBox.Value;
                    % Call routine OptArgsListBoxChanged to store modification associated to previous values and include all empty items associated to value which has just beene added
                    OptArgsListBoxChanged(app, ev)
                end
            end
        end

        % Button pushed function: DelOptArg
        function DelOptArgButtonPushed(app, event)
            
            % Selected argument will be deleted from the listbox
            app.OptArgsListBox.Items(app.OptArgsListBox.Value)=[];
            
            % Selected argument will be deleted from the original cell
            if ~isempty(app.OptArgsListBox.Value)
                app.out.OptArgs(app.OptArgsListBox.Value,:)=[];
            else
                app.out.OptArgs=cell(1,7);
            end
            fillOptArgs(app,1)
        end

        % Button pushed function: AddOutArg
        function AddOutArgButtonPushed(app, event)
            
            
            %             prompt = {'Enter name of new output argument:','If it is a structure write struct:'};
            %             dlg_title = 'Name of new output argument (or varargout)';
            %             num_lines = 1;
            %             defaultans = {'','struct'};
            %             answer = inputdlg(prompt,dlg_title,num_lines,defaultans);
            %             newValue=answer(1);
            %
            %             app.out.OutArgs(end+1,1:end)={''};
            %             app.OutArgsListBox.Items=[app.OutArgsListBox.Items newValue];
            %             app.OutArgsListBox.ItemsData=1:length(app.OutArgsListBox.Items);
            %             app.out.OutArgs(end,1)=newValue;
            %             if strcmp(answer(end),'struct')
            %                 app.out.OutArgs(end,4)=cell(1,1);
            %             else
            %                 app.out.OutArgs{end,2}='xxx';
            %             end
            
            % A new argument will be added to the listBox
            newValue= inputdlg('Enter new output argument',...
                'New item', [1 50]);
            if ~isempty(newValue)
                app.out.OutArgs(end+1,1:end)={''};
                app.OutArgsListBox.Items=[app.OutArgsListBox.Items newValue];
                app.OutArgsListBox.ItemsData=1:length(app.OutArgsListBox.Items);
                app.out.OutArgs(end,1)=newValue;
                
                app.out.OutArgs{end,end}=[];
                
                % Store previous selection in the listbox
                ev=struct;
                ev.PreviousValue=app.OutArgsListBox.Value;
                
                scroll(app.OutArgsListBox,'bottom');
                app.OutArgsListBox.Value=length(app.OutArgsListBox.Items);
                
                % Store current selection in the listbox
                ev.Value=app.OutArgsListBox.Value;
                % Call routine OutArgsListBoxChanged to store modification associated to previous values and include all empty items associated to value which has just beene added
                OutArgsListBoxChanged(app, ev)
            end
        end

        % Value changed function: OutArgsListBox
        function OutArgsListBoxChanged(app, event)
            value = event.PreviousValue;
            if isempty(value)
                value=1;
            end
            % make sure there is nothing pending
            drawnow;
            % Use previous value to store modifications insice the textboxes ExMatlabCode and ExDescription
            app.out.OutArgs{value,3}=sprintf('%s\n',app.OutArgsTypeIndication.Value{:});
            app.out.OutArgs{value,2}=sprintf('%s\n',app.OutArgsShortDesc.Value{:});
            app.out.OutArgs{value,4}=sprintf('%s\n',app.OutArgsLongDesc.Value{:});
            
            
            if app.OutStructure.Value == true
                app.out.OutArgs{value,5}=app.OutArgsUITable.Data;
            else
                app.out.OutArgs{value,5}='';
            end
            
            
            % Once modifications have been stored, use current value of the listbox to show the corresponding items assocaited to new selection
            
            value = app.OutArgsListBox.Value;
            fillOutArgs(app,value)
        end

        % Button pushed function: DelOutArg
        function DelOutArgButtonPushed(app, event)
            
            
            % Selected argument will be deleted from the listbox
            app.OutArgsListBox.Items(app.OutArgsListBox.Value)=[];
            
            % Selected argument will be deleted from the original cell
            if ~isempty(app.OutArgsListBox.Value)
                app.out.OutArgs(app.OutArgsListBox.Value,:)=[];
            else
                app.out.OutArgs=cell(1,5);
            end
            fillOutArgs(app,1)
            
        end

        % Button pushed function: AddSeeAlsoArg
        function AddSeeAlsoArgButtonPushed(app, event)
            newValue= inputdlg('Enter new See Also argument',...
                'New item', [1 50]);
            app.out.SeeAlso(end+1,1:end)={''};
            app.SeeAlsoListBox.Items=[app.SeeAlsoListBox.Items newValue];
            app.SeeAlsoListBox.ItemsData=1:length(app.SeeAlsoListBox.Items);
            app.out.SeeAlso(end,1)=newValue;
            
        end

        % Button pushed function: DelSeeAlsoArg
        function DelSeeAlsoArgButtonPushed(app, event)
            value = app.SeeAlsoListBox.Value;
            % Selected argument will be deleted
            app.SeeAlsoListBox.Items(value)=[];
            % and from the original cell
            app.out.SeeAlso(value)=[];
        end

        % Value changed function: ExListBox
        function ExListBoxValueChanged(app, event)
            value = event.PreviousValue;
            % make sure there is nothing pending
            drawnow;
            % Use previous value to store modifications insice the textboxes ExMatlabCode and ExDescription
            app.out.Ex{value,3}=sprintf('%s\n',app.ExMatlabCode.Value{:});
            app.out.Ex{value,2}=sprintf('%s\n',app.ExDescription.Value{:});
            % Once modifications have been stored use, current value of the listbox to show the corresponding items assocaited to new selection
            value = event.Value;
            fillExArgs(app,value)
        end

        % Button pushed function: AddEx
        function AddExButtonPushed(app, event)
            % A new argument will be added to the listBox
            newValue= inputdlg('Enter new example ',...
                'New item', [1 50]);
            if ~isempty(newValue)
                app.out.Ex(end+1,1:end)={''};
                app.ExListBox.Items=[app.ExListBox.Items newValue];
                app.ExListBox.ItemsData=1:length(app.ExListBox.Items);
                app.out.Ex(end,1)=newValue;
                
                % Store previous selection in the listbox
                ev=struct;
                ev.PreviousValue=app.ExListBox.Value;
                
                scroll(app.ExListBox,'bottom');
                app.ExListBox.Value=length(app.ExListBox.Items);
                
                % Store current selection in the listbox
                ev.Value=app.ExListBox.Value;
                % Call routine ExListBoxChanged to store modification associated to previous values and include all empty items associated to value which has just beene added
                ExListBoxValueChanged(app, ev)
            end
            
        end

        % Button pushed function: DelEx
        function DelExButtonPushed(app, event)
            value = app.ExListBox.Value;
            % Selected argument will be deleted
            app.ExListBox.Items(value)=[];
            % and from the original cell
            app.out.Ex(value,:)=[];
            % call routine ExListBoxChanged because all the correlated elements must be deleted
            ExListBoxValueChanged(app, event)
        end

        % Value changed function: ExtoExecute
        function ExtoExecuteValueChanged(app, event)
            value = app.ExtoExecute.Value;
            if value
                app.out.Ex{app.ExListBox.Value,4}='1';
            else
                app.out.Ex{app.ExListBox.Value,4}='0';
            end
        end

        % Value changed function: RequiredInput
        function RequiredInputValueChanged(app, event)
            value = app.RequiredInput.Value;
            
            if value
                app.out.InpArgs{app.InpArgsListBox.Value,7}='1';
            else
                app.out.InpArgs{app.InpArgsListBox.Value,7}='0';
            end
        end

        % Value changed function: OutStructure
        function OutStructureValueChanged(app, event)
            value = app.OutStructure.Value;
            drawnow;
            if value
                choice = questdlg('A new UItable will be shown. Are you sure?', ...
                   	'Confirm addition', ...
                   	'Yes','No');
                % Handle response
                if strcmp(choice,'Yes')
                    
                    app.out.OutArgs{app.OutArgsListBox.Value,5}=cell(10,2);
                end                
            else
                % Just obtain a confirmation from the user
                choice = questdlg('All the elements inside the UItable for this output arguemnt will be deleted. Are you sure?', ...
                   	'Delete the associated UItable', ...
                   	'Yes','No');
                % Handle response
                if strcmp(choice,'Yes')
                    app.out.OutArgs{app.OutArgsListBox.Value,5}='';
                end
            end
            
            fillOutArgs(app,app.OutArgsListBox.Value)
        end

        % Button pushed function: AddExtraEx
        function AddExtraExButtonPushed(app, event)
            % A new argument will be added to the listBox
            newValue= inputdlg('Enter new extra example ',...
                'New item', [1 50]);
            if ~isempty(newValue)
                app.out.ExtraEx(end+1,1:end)={''};
                app.ExtraExListBox.Items=[app.ExtraExListBox.Items newValue];
                app.ExtraExListBox.ItemsData=1:length(app.ExtraExListBox.Items);
                app.out.ExtraEx(end,1)=newValue;
                
                
                % Store previous selection in the listbox
                ev=struct;
                ev.PreviousValue=app.ExtraExListBox.Value;
                
                scroll(app.ExtraExListBox,'bottom');
                app.ExtraExListBox.Value=length(app.ExtraExListBox.Items);
                
                % Store current selection in the listbox
                ev.Value=app.ExtraExListBox.Value;
                % Call routine ExtraExListBoxChanged to store modification associated to previous values and include all empty items associated to value which has just beene added
                ExtraExListBoxValueChanged(app, ev)
            end
            
        end

        % Value changed function: ExtraExListBox
        function ExtraExListBoxValueChanged(app, event)
            
            value = event.PreviousValue;
            % make sure there is nothing pending
            drawnow;
            % Use previous value to store modifications insice the textboxes ExMatlabCode and ExDescription
            app.out.ExtraEx{value,3}=sprintf('%s\n',app.ExtraExMatlabCode.Value{:});
            app.out.ExtraEx{value,2}=sprintf('%s\n',app.ExtraExDescription.Value{:});
            % Once modifications have been stored use, current value of the listbox to show the corresponding items assocaited to new selection
            value = event.Value;
            % value = app.ExtraExListBox.Value;
            fillExtraExArgs(app,value)
        end

        % Button pushed function: DelExtraEx
        function DelExtraExButtonPushed(app, event)
            value = app.ExtraExListBox.Value;
            % Selected argument will be deleted
            app.ExtraExListBox.Items(value)=[];
            % and from the original cell
            app.out.ExtraEx(value,:)=[];
            % call routine ExListBoxChanged because all the correlated elements must be deleted
            ExtraExListBoxValueChanged(app, event)
        end

        % Value changed function: ExtraExtoExecute
        function ExtraExtoExecuteValueChanged(app, event)
            
            value = app.ExtraExtoExecute.Value;
            if value
                app.out.ExtraEx{app.ExtraExListBox.Value,4}='1';
            else
                app.out.ExtraEx{app.ExtraExListBox.Value,4}='0';
            end
        end

        % Button pushed function: SavechangestoXMLfileButton
        function SavechangestoXMLfileButtonPushed(app, event)
            %           fsep=filesep;
            %fullpathFileName=[FSDAroot fsep 'helpfiles' fsep 'XML' fsep 'InputStructForHelpGUI'];
            %save(fullpathFileName,'InputStructForHelpGUI')
            app.out.titl=app.Name.Value;
            app.out.purpose=app.Purpose.Value;
            app.out.description=app.Description.Value;
            app.out.MoreAbout=app.MoreAbout.Value;
            app.out.Acknowledgements=app.Acknowledgements.Value;
            app.out.References= app.References.Value;
            
            
            % Store what is in tab Input Arguments
            ev=struct;
            i=app.InpArgsListBox.Value;
            ev.PreviousValue=i;
            ev.Value=i;
            ListBoxChanged(app,ev)
            
            % Store what is in tab Name/Value Opt. Input arguments
            i=app.OptArgsListBox.Value;
            ev.PreviousValue=i;
            ev.Value=i;
            OptArgsListBoxChanged(app,ev)
            
            % Store what is in tab  output arguments
            i=app.OutArgsListBox.Value;
            ev.PreviousValue=i;
            ev.Value=i;
            OutArgsListBoxChanged(app,ev)
            
            % Store what is in tab Examples
            i=app.ExListBox.Value;
            app.out.Ex{i,2}=app.ExDescription.Value;
            app.out.Ex{i,3}=app.ExMatlabCode.Value;
            if app.ExtoExecute.Value
                app.out.Ex{i,4}='1';
            else
                app.out.Ex{i,4}='0';
            end
            
            % Store what is in tab Extra Examples
            i=app.ExtraExListBox.Value;
            app.out.ExtraEx{i,2}=app.ExtraExDescription.Value;
            app.out.ExtraEx{i,3}=app.ExtraExMatlabCode.Value;
            if app.ExtraExtoExecute.Value
                app.out.ExtraEx{i,4}='1';
            else
                app.out.ExtraEx{i,4}='0';
            end
            
            % write XML file
            xmlwriteFS(app.out);
            disp('Document Saved:')
            datetime('now')
        end

        % Value changed function: InpStructure
        function InpStructureValueChanged(app, event)
            value = app.InpStructure.Value;
            if value
                if isempty(app.out.InpArgs{app.InpArgsListBox.Value,8})
                    app.out.InpArgs{app.InpArgsListBox.Value,8}=cell(1,2);
                end
            else
                app.InpArgsUITable.Data='';
                app.out.InpArgs{app.InpArgsListBox.Value,8}='';
            end
            fillInputArgs(app,app.InpArgsListBox.Value)
        end

        % Value changed function: ShortDesc
        function ShortDescValueChanged(app, event)
            % value = app.ShortDesc.Value;
            %app.out.InpArgs{app.InpArgsListBox.Value,2}=app.ShortDesc.Value{:};
        end

        % Value changed function: TypeIndication
        function TypeIndicationValueChanged(app, event)
            %app.out.InpArgs{app.InpArgsListBox.Value,3}=app.TypeIndication.Value{:};
        end

        % Value changed function: LongDesc
        function LongDescValueChanged(app, event)
            value = app.LongDesc.Value;
            %app.out.InpArgs{app.InpArgsListBox.Value,4}=value{:};
        end

        % Value changed function: Example
        function ExampleValueChanged(app, event)
            value = app.Example.Value;
            %app.out.InpArgs{app.InpArgsListBox.Value,5}=value;
        end

        % Value changed function: DataType
        function DataTypeValueChanged(app, event)
            value = app.DataType.Value;
            %app.out.InpArgs{app.InpArgsListBox.Value,6}=value;
        end


        function InpArgsUITableCellEdit(app, event)
            %  app.out.InpArgs{app.InpArgsListBox.Value,8}=app.InpArgsUITable.Data;
            % check what is the number of non empty rows of the UITable
            UITab=app.InpArgsUITable.Data;
            
            Tab=app.InpArgsUITable.Data(:,1);
            boo=cellfun(@isempty,Tab);
            UITab=UITab(~boo,:);
            UITab=[UITab; cell(1,2)];
            Tab=Tab(~boo);
            % app.out.InpArgs{app.InpArgsListBox.Value,8}
            app.InpArgsListBoxStruct.Items=Tab';
            %app.InpArgsListBoxStruct.ItemsData=1:length(Tab);
            % app.InpArgsListBoxStruct.ItemsData=1:length(app.InpArgsListBoxStruct.Items);
            app.out.InpArgs{app.InpArgsListBox.Value,8}=UITab;
            app.InpArgsUITable.Data=UITab;
            index=event.Indices(1);
            % Change the corresponding description (inside InpArgsZoom)
            if isempty(UITab{index,2})
                app.InpArgsZoom.Value='';
            else
                app.InpArgsZoom.Value=UITab{index,2};
            end
            % Change the corresponding selection inside the listbox (inside  InpArgsListBoxStruct)
            drawnow;
            % try
            scroll(app.InpArgsListBoxStruct,app.InpArgsListBoxStruct.Items{index})
            app.InpArgsListBoxStruct.Value=app.InpArgsListBoxStruct.Items{index};
            %  catch
            %                 scroll(app.InpArgsListBoxStruct,'bottom')
            %                 app.InpArgsListBoxStruct.Value=app.InpArgsListBoxStruct.Items{end};
            %                 index=length(app.InpArgsListBoxStruct.Items);
            %
            %ddd=1;
            %   end
            
        end

        % Value changed function: OptArgsShortDesc
        function OptArgsShortDescValueChanged(app, event)
            value = app.OptArgsShortDesc.Value;
            %   app.out.OptArgs{app.OptArgsListBox.Value,2}=value{:};
        end

        % Value changed function: OptArgsTypeIndication
        function OptArgsTypeIndicationValueChanged(app, event)
            value = app.OptArgsTypeIndication.Value;
            %   app.out.OptArgs{app.OptArgsListBox.Value,3}=value{:};
        end

        % Value changed function: OptArgsLongDesc
        function OptArgsLongDescValueChanged(app, event)
            value = app.OptArgsLongDesc.Value;
            %   app.out.OptArgs{app.OptArgsListBox.Value,4}=value{:};
        end

        % Value changed function: OptArgsExample
        function OptArgsExampleValueChanged(app, event)
            value = app.OptArgsExample.Value;
            %   app.out.OptArgs{app.OptArgsListBox.Value,5}=value{:};
        end

        % Value changed function: OptArgsDataType
        function OptArgsDataTypeValueChanged(app, event)
            value = app.OptArgsDataType.Value;
            %   app.out.OptArgs{app.OptArgsListBox.Value,6}=value{:};
        end


        function OptArgsUITableCellEdit(app, event)
            app.out.OptArgs{app.OptArgsListBox.Value,7}=app.OptArgsUITable.Data;
        end

        % Value changed function: OptStructure
        function OptStructureValueChanged(app, event)
            value = app.OptStructure.Value;
            if value
                if isempty(app.out.OptArgs{app.OptArgsListBox.Value,7})
                    app.out.OptArgs{app.OptArgsListBox.Value,7}=cell(10,2);
                end
                
            end
            fillOptArgs(app,app.OptArgsListBox.Value)
        end


        function InpArgsUITableCellSelection(app, event)
            %             if event.Indices(2)==2
            %             % app.out.InpArgs{app.InpArgsListBox.Value,8}=app.InpArgsUITable.Data;
            %             Zoom=app.out.InpArgs{app.InpArgsListBox.Value,8};
            %             if iscell( app.InpArgsZoom.Value)
            %                 if ~isempty(app.InpArgsZoom.Value{:})
            %             Zoom(event.Indices(2),2)=app.InpArgsZoom.Value{:};
            %             app.out.InpArgs{app.InpArgsListBox.Value,8}=Zoom;
            %                 end
            %             end
            %             end
            %             app.out.event=event;
            
            % First of all store in the table what been included inside the description
            drawnow
            valuestr = app.InpArgsListBoxStruct.Value;
            seq=1:length(app.InpArgsListBoxStruct.Items);
            value=seq(strcmp(valuestr,app.InpArgsListBoxStruct.Items));
            TAB=app.out.InpArgs{app.InpArgsListBox.Value,8};
            
            if iscell(app.InpArgsZoom.Value)
                drawnow
                spri=sprintf('%s\n',app.InpArgsZoom.Value{:});
                drawnow
                TAB{value,2}=spri;
%                                  disp('tab2')
%                                  disp(TAB{value,2})
                if isempty(strtrim(TAB{value,2}))
                    pause(0.1)
                    TAB{value,2}=sprintf('%s\n',app.InpArgsZoom.Value{:});
                end
            end
            
            app.out.InpArgs{app.InpArgsListBox.Value,8}=TAB;
            if ~isempty(TAB{end,1}) && ~isempty(TAB{end,2})
                app.InpArgsUITable.Data=[TAB; cell(1,2)];
            else
                app.InpArgsUITable.Data=TAB;
            end
            
            % Now show the proper values in the listboxstruct
            drawnow;
            try
                index=event.Indices(1);
                scroll(app.InpArgsListBoxStruct,app.InpArgsListBoxStruct.Items{index})
                app.InpArgsListBoxStruct.Value=app.InpArgsListBoxStruct.Items{index};
                %                             scroll(app.InpArgsListBoxStruct,app.InpArgsListBoxStruct.ItemsData(index))
                %                 app.InpArgsListBoxStruct.Value=app.InpArgsListBoxStruct.Items{index};
            catch
                scroll(app.InpArgsListBoxStruct,'bottom')
                app.InpArgsListBoxStruct.Value=app.InpArgsListBoxStruct.Items{end};
                index=length(app.InpArgsListBoxStruct.Items);
                
            end
            % scroll(app.InpArgsListBoxStruct,event.Indices(1));
            Zoom=app.out.InpArgs{app.InpArgsListBox.Value,8};
            if ~cellfun(@isempty,Zoom(index,2))
                app.InpArgsZoom.Value=Zoom{index,2};
            end
        end

        % Value changed function: ExDescription
        function ExDescriptionValueChanged(app, event)
            
            %  app.out.Ex{app.ExListBox.Value,2}=app.ExDescription.Value{:};
        end

        % Value changed function: ExMatlabCode
        function ExMatlabCodeValueChanged(app, event)
            
            % app.out.Ex{app.ExListBox.Value,3}=app.ExMatlabCode.Value{:};
            %  app.out.Ex{app.ExListBox.Value,3}=sprintf('%s\n',app.ExMatlabCode.Value{:});
        end

        % Value changed function: InpArgsZoom
        function InpArgsZoomValueChanged(app, event)
            ddd=1;
            
        end

        % Value changed function: InpArgsListBoxStruct
        function InpArgsListBoxStructValueChanged(app, event)
            
            %  value = app.InpArgsZoom.Value{:};
            % app.InpArgsUITable.Data{app.out.event.Indices(1),2}=value;
            
            valuestr = event.PreviousValue;
            
            % itemsdata property of listbox has ot been defined therefore it is necessary to convert string  into num


            if isempty(valuestr)
                value=1;
            else
                seq=1:length(event.Source.Items);
                value=seq(strcmp(valuestr,event.Source.Items));
            end
            % make sure there is nothing pending
            drawnow;
            % Use previous value to store modifications inside the UItable
            TAB=app.out.InpArgs{app.InpArgsListBox.Value,8};
            if iscell(app.InpArgsZoom.Value)
                TAB{value,2}=sprintf('%s\n',app.InpArgsZoom.Value{:});
            end
            app.out.InpArgs{app.InpArgsListBox.Value,8}=TAB;
            app.InpArgsUITable.Data=TAB;
            
            
            valuestr = app.InpArgsListBoxStruct.Value;
            seq=1:length(event.Source.Items);
            value=seq(strcmp(valuestr,event.Source.Items));
            
            % fillInputArgsStruct(app,value)
            
            % TAB= app.InpArgsUITable.Data;
            
            if ~isempty(TAB{value,2})
                app.InpArgsZoom.Value=TAB{value,2};
            else
                app.InpArgsZoom.Value='';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, false)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabPreamble = uitab(app.TabGroup);
            app.TabPreamble.Title = 'Preamble';
            app.TabPreamble.Units = 'pixels';
            app.LabelEditField = uilabel(app.TabPreamble);
            app.LabelEditField.Text = 'FileName';
            app.Name = uieditfield(app.TabPreamble, 'text');
            app.LabelTextArea = uilabel(app.TabPreamble);
            app.LabelTextArea.Text = 'Purpose';
            app.Purpose = uitextarea(app.TabPreamble);
            app.LabelTextArea2 = uilabel(app.TabPreamble);
            app.LabelTextArea2.Text = 'Description';
            app.Description = uitextarea(app.TabPreamble);
            app.Label2 = uilabel(app.TabPreamble);
            app.Label2.Text = 'Name of the file';
            app.Label3 = uilabel(app.TabPreamble);
            app.Label3.Text = 'Purpose contains the second line of the file';
            app.Label4 = uilabel(app.TabPreamble);
            app.Label4.Text = 'Description contains the text to include before "Required Input Arguments"';
            app.TabReqArgs = uitab(app.TabGroup);
            app.TabReqArgs.Title = 'Input Arguments';
            app.TabReqArgs.Units = 'pixels';
            app.LabelListBox = uilabel(app.TabReqArgs);
            app.LabelListBox.Text = {'List of Required '; 'Input arguments'};
            app.InpArgsListBox = uilistbox(app.TabReqArgs);
            app.InpArgsListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxChanged, true);
            app.LabelTextArea3 = uilabel(app.TabReqArgs);
            app.LabelTextArea3.Text = 'Short Description';
            app.ShortDesc = uitextarea(app.TabReqArgs);
            app.ShortDesc.ValueChangedFcn = createCallbackFcn(app, @ShortDescValueChanged, true);
            app.Label = uilabel(app.TabReqArgs);
            app.Label.Text = 'Long Description';
            app.LongDesc = uitextarea(app.TabReqArgs);
            app.LongDesc.ValueChangedFcn = createCallbackFcn(app, @LongDescValueChanged, true);
            app.Label5 = uilabel(app.TabReqArgs);
            app.Label5.Text = {'Check this box if the argument'; 'is required'};
            app.Label6 = uilabel(app.TabReqArgs);
            app.Label6.Text = 'Type indication';
            app.TypeIndication = uitextarea(app.TabReqArgs);
            app.TypeIndication.ValueChangedFcn = createCallbackFcn(app, @TypeIndicationValueChanged, true);
            app.Label7 = uilabel(app.TabReqArgs);
            app.Label7.Text = {'Type indication extracts what is contained before the fist and second full stop '; '(for example: scalar, vector of size 2, ...) '};
            app.Label8 = uilabel(app.TabReqArgs);
            app.Label8.Text = {'Long Description extracts what is contained after the second full stop '; '(this is the long description of the required input argument) '};
            app.RequiredInput = uicheckbox(app.TabReqArgs);
            app.RequiredInput.ValueChangedFcn = createCallbackFcn(app, @RequiredInputValueChanged, true);
            app.RequiredInput.Text = 'Required Input Arguement';
            app.Label9 = uilabel(app.TabReqArgs);
            app.Label9.Text = 'Short description extracts what is contained before the fist full stop (This is a syntethic description)';
            app.LabelEditField2 = uilabel(app.TabReqArgs);
            app.LabelEditField2.Text = 'Example';
            app.Example = uieditfield(app.TabReqArgs, 'text');
            app.Example.ValueChangedFcn = createCallbackFcn(app, @ExampleValueChanged, true);
            app.AddInpArg = uibutton(app.TabReqArgs, 'push');
            app.AddInpArg.ButtonPushedFcn = createCallbackFcn(app, @AddInpArgButtonPushed, true);
            app.AddInpArg.Text = 'Add Input Argument';
            app.DelInpArg = uibutton(app.TabReqArgs, 'push');
            app.DelInpArg.ButtonPushedFcn = createCallbackFcn(app, @DelInpArgButtonPushed, true);
            app.DelInpArg.Text = {'Delete Selected '; 'Input Argument'};
            app.Label10 = uilabel(app.TabReqArgs);
            app.Label10.Text = 'DataType';
            app.DataType = uieditfield(app.TabReqArgs, 'text');
            app.DataType.ValueChangedFcn = createCallbackFcn(app, @DataTypeValueChanged, true);
            app.InpArgsUITable = uitable(app.TabReqArgs);
            app.InpArgsUITable.ColumnName = {'Value'; 'Description'};
            app.InpArgsUITable.RowName = {};
            app.InpArgsUITable.ColumnEditable = [true false];
            app.InpArgsUITable.CellEditCallback = createCallbackFcn(app, @InpArgsUITableCellEdit, true);
            app.InpArgsUITable.CellSelectionCallback = createCallbackFcn(app, @InpArgsUITableCellSelection, true);
            app.InpStructure = uicheckbox(app.TabReqArgs);
            app.InpStructure.ValueChangedFcn = createCallbackFcn(app, @InpStructureValueChanged, true);
            app.InpStructure.Text = 'Struct';
            app.Label5_5 = uilabel(app.TabReqArgs);
            app.Label5_5.Text = {'Check this box if the argument'; 'is a structure'};
            app.InpArgsZoom = uitextarea(app.TabReqArgs);
            app.InpArgsZoom.ValueChangedFcn = createCallbackFcn(app, @InpArgsZoomValueChanged, true);
            app.InpArgsStructurevalueLabel = uilabel(app.TabReqArgs);
            app.InpArgsStructurevalueLabel.Text = 'Structure value';
            app.InpArgsListBoxStruct = uilistbox(app.TabReqArgs);
            app.InpArgsListBoxStruct.ValueChangedFcn = createCallbackFcn(app, @InpArgsListBoxStructValueChanged, true);
            app.InpArgsStructuredescriptionLabel = uilabel(app.TabReqArgs);
            app.InpArgsStructuredescriptionLabel.Text = 'Structure description';
            app.TabOptArgs = uitab(app.TabGroup);
            app.TabOptArgs.Title = 'Name/Value Opt. Input arguments';
            app.TabOptArgs.Units = 'pixels';
            app.Label7_2 = uilabel(app.TabOptArgs);
            app.Label7_2.Text = {'Type indication extracts what is contained before the fist and second full stop '; '(for example: scalar, vector of size 2, ...) '};
            app.Label8_2 = uilabel(app.TabOptArgs);
            app.Label8_2.Text = 'Long Description extracts what is contained after the second full stop';
            app.Label9_2 = uilabel(app.TabOptArgs);
            app.Label9_2.Text = {'Short description extracts what is contained before the fist full stop '; 'This is a syntethic description ot the input argument'};
            app.AddOptArg = uibutton(app.TabOptArgs, 'push');
            app.AddOptArg.ButtonPushedFcn = createCallbackFcn(app, @AddOptArgButtonPushed, true);
            app.AddOptArg.Text = 'Add Optional Input Argument';
            app.DelOptArg = uibutton(app.TabOptArgs, 'push');
            app.DelOptArg.ButtonPushedFcn = createCallbackFcn(app, @DelOptArgButtonPushed, true);
            app.DelOptArg.Text = {'Delete Selected '; 'Optional Input Argument'};
            app.ListofOptionalInputargumentsLabel = uilabel(app.TabOptArgs);
            app.ListofOptionalInputargumentsLabel.Text = {'List of Optional'; 'Input arguments'};
            app.OptArgsListBox = uilistbox(app.TabOptArgs);
            app.OptArgsListBox.ValueChangedFcn = createCallbackFcn(app, @OptArgsListBoxChanged, true);
            app.ShortDesc_2Label = uilabel(app.TabOptArgs);
            app.ShortDesc_2Label.Text = 'Short Description';
            app.OptArgsShortDesc = uitextarea(app.TabOptArgs);
            app.OptArgsShortDesc.ValueChangedFcn = createCallbackFcn(app, @OptArgsShortDescValueChanged, true);
            app.LongDesc_2Label = uilabel(app.TabOptArgs);
            app.LongDesc_2Label.Text = 'Long Description';
            app.OptArgsLongDesc = uitextarea(app.TabOptArgs);
            app.OptArgsLongDesc.ValueChangedFcn = createCallbackFcn(app, @OptArgsLongDescValueChanged, true);
            app.TypeIndication_2Label = uilabel(app.TabOptArgs);
            app.TypeIndication_2Label.Text = 'Type indication';
            app.OptArgsTypeIndication = uitextarea(app.TabOptArgs);
            app.OptArgsTypeIndication.ValueChangedFcn = createCallbackFcn(app, @OptArgsTypeIndicationValueChanged, true);
            app.Example_2Label = uilabel(app.TabOptArgs);
            app.Example_2Label.Text = 'Example';
            app.OptArgsExample = uieditfield(app.TabOptArgs, 'text');
            app.OptArgsExample.ValueChangedFcn = createCallbackFcn(app, @OptArgsExampleValueChanged, true);
            app.DataType_2Label = uilabel(app.TabOptArgs);
            app.DataType_2Label.Text = 'DataType';
            app.OptArgsDataType = uieditfield(app.TabOptArgs, 'text');
            app.OptArgsDataType.ValueChangedFcn = createCallbackFcn(app, @OptArgsDataTypeValueChanged, true);
            app.OptArgsUITable = uitable(app.TabOptArgs);
            app.OptArgsUITable.ColumnName = {'Value'; 'Description'};
            app.OptArgsUITable.RowName = {};
            app.OptArgsUITable.ColumnEditable = true;
            app.OptArgsUITable.CellEditCallback = createCallbackFcn(app, @OptArgsUITableCellEdit, true);
            app.OptStructure = uicheckbox(app.TabOptArgs);
            app.OptStructure.ValueChangedFcn = createCallbackFcn(app, @OptStructureValueChanged, true);
            app.OptStructure.Text = 'Struct';
            app.Label5_6 = uilabel(app.TabOptArgs);
            app.Label5_6.Text = {'Check this box if the argument'; 'is a structure'};
            app.TabOutArgs = uitab(app.TabGroup);
            app.TabOutArgs.Title = 'Output';
            app.TabOutArgs.Units = 'pixels';
            app.OutputArgumentsLabel = uilabel(app.TabOutArgs);
            app.OutputArgumentsLabel.Text = 'Output Arguments';
            app.OutArgsListBox = uilistbox(app.TabOutArgs);
            app.OutArgsListBox.ValueChangedFcn = createCallbackFcn(app, @OutArgsListBoxChanged, true);
            app.OutArgsTypeIndicationLabel = uilabel(app.TabOutArgs);
            app.OutArgsTypeIndicationLabel.Text = {'Type indication extracts what is contained before the fist and second full stop '; '(for example: scalar, vector of size 2, ...) '};
            app.OutArgsLongDescLabel = uilabel(app.TabOutArgs);
            app.OutArgsLongDescLabel.Text = {'Long Description extracts what is contained after the second full stop '; '(this is the long description of the output argument) '};
            app.OutArgsShortDescLabel = uilabel(app.TabOutArgs);
            app.OutArgsShortDescLabel.Text = {'Short description extracts what is contained before the fist full stop '; 'This is a syntethic description ot the output argument'};
            app.OutArgsShortDescLabel1 = uilabel(app.TabOutArgs);
            app.OutArgsShortDescLabel1.Text = 'Short Description';
            app.OutArgsShortDesc = uitextarea(app.TabOutArgs);
            app.OutArgsLongDescLabel1 = uilabel(app.TabOutArgs);
            app.OutArgsLongDescLabel1.Text = 'Long Description';
            app.OutArgsLongDesc = uitextarea(app.TabOutArgs);
            app.OutArgsTypeIndicationLabel1 = uilabel(app.TabOutArgs);
            app.OutArgsTypeIndicationLabel1.Text = 'Type indication';
            app.OutArgsTypeIndication = uitextarea(app.TabOutArgs);
            app.OutArgsUITable = uitable(app.TabOutArgs);
            app.OutArgsUITable.ColumnName = {'Value'; 'Description'};
            app.OutArgsUITable.RowName = {};
            app.OutArgsUITable.ColumnEditable = [false true];
            app.Label5_2 = uilabel(app.TabOutArgs);
            app.Label5_2.Text = {'Check this box if the output argument'; 'is s struct (in this case the'; ' UItable will be filled)'; 'else "Short Description", "Type indication" '; 'and "Long Description" will appear'};
            app.OutStructure = uicheckbox(app.TabOutArgs);
            app.OutStructure.ValueChangedFcn = createCallbackFcn(app, @OutStructureValueChanged, true);
            app.OutStructure.Text = 'Struct';
            app.AddOutArg = uibutton(app.TabOutArgs, 'push');
            app.AddOutArg.ButtonPushedFcn = createCallbackFcn(app, @AddOutArgButtonPushed, true);
            app.AddOutArg.Text = 'Add Output Argument';
            app.DelOutArg = uibutton(app.TabOutArgs, 'push');
            app.DelOutArg.ButtonPushedFcn = createCallbackFcn(app, @DelOutArgButtonPushed, true);
            app.DelOutArg.Text = {'Delete Selected '; 'Output Argument'};
            app.Label7_4 = uilabel(app.TabOutArgs);
            app.Label7_4.Text = {'If the output argument is a struct then it is necessary just to write the fieldnames of the structure (in column value) '; 'and the corresponding fields inside Description'};
            app.TabMoreAbout = uitab(app.TabGroup);
            app.TabMoreAbout.Title = 'More About';
            app.TabMoreAbout.Units = 'pixels';
            app.MoreAboutLabel = uilabel(app.TabMoreAbout);
            app.MoreAboutLabel.Text = 'More About';
            app.MoreAbout = uitextarea(app.TabMoreAbout);
            app.AcknowledgementsLabel = uilabel(app.TabMoreAbout);
            app.AcknowledgementsLabel.Text = 'Acknowledgements';
            app.Acknowledgements = uitextarea(app.TabMoreAbout);
            app.ReferencesLabel = uilabel(app.TabMoreAbout);
            app.ReferencesLabel.Text = 'References';
            app.References = uitextarea(app.TabMoreAbout);
            app.SeeAlsoListBoxLabel = uilabel(app.TabMoreAbout);
            app.SeeAlsoListBoxLabel.Text = 'See Also';
            app.SeeAlsoListBox = uilistbox(app.TabMoreAbout);
            app.AddSeeAlsoArg = uibutton(app.TabMoreAbout, 'push');
            app.AddSeeAlsoArg.ButtonPushedFcn = createCallbackFcn(app, @AddSeeAlsoArgButtonPushed, true);
            app.AddSeeAlsoArg.Text = 'Add See Also Argument';
            app.DelSeeAlsoArg = uibutton(app.TabMoreAbout, 'push');
            app.DelSeeAlsoArg.ButtonPushedFcn = createCallbackFcn(app, @DelSeeAlsoArgButtonPushed, true);
            app.DelSeeAlsoArg.Text = {'Delete See Also'; 'Argument'};
            app.TabExamples = uitab(app.TabGroup);
            app.TabExamples.Title = 'Examples';
            app.TabExamples.Units = 'pixels';
            app.Label5_3 = uilabel(app.TabExamples);
            app.Label5_3.Text = {'Check this box if the example code '; 'has to be executed '; 'and its output must be shown '; 'in the HTML output file'};
            app.Label7_5 = uilabel(app.TabExamples);
            app.Label7_5.Text = 'Write here the purpose of the example together with a description';
            app.Label8_3 = uilabel(app.TabExamples);
            app.Label8_3.Text = 'Insert here the MATLAB code (adding comments with usual symbol %)';
            app.ExtoExecute = uicheckbox(app.TabExamples);
            app.ExtoExecute.ValueChangedFcn = createCallbackFcn(app, @ExtoExecuteValueChanged, true);
            app.ExtoExecute.Text = 'Example to Execute';
            app.AddEx = uibutton(app.TabExamples, 'push');
            app.AddEx.ButtonPushedFcn = createCallbackFcn(app, @AddExButtonPushed, true);
            app.AddEx.Text = 'Add Example';
            app.DelEx = uibutton(app.TabExamples, 'push');
            app.DelEx.ButtonPushedFcn = createCallbackFcn(app, @DelExButtonPushed, true);
            app.DelEx.Text = {'Delete Selected '; 'Example'};
            app.MatlabcodewithcommentsLabel = uilabel(app.TabExamples);
            app.MatlabcodewithcommentsLabel.Text = {'Matlab code '; '(with comments)'};
            app.ExMatlabCode = uitextarea(app.TabExamples);
            app.ExMatlabCode.ValueChangedFcn = createCallbackFcn(app, @ExMatlabCodeValueChanged, true);
            app.ExampledescriptionLabel = uilabel(app.TabExamples);
            app.ExampledescriptionLabel.Text = 'Example description';
            app.ExDescription = uitextarea(app.TabExamples);
            app.ExDescription.ValueChangedFcn = createCallbackFcn(app, @ExDescriptionValueChanged, true);
            app.TitleoftheexampleLabel = uilabel(app.TabExamples);
            app.TitleoftheexampleLabel.Text = {'Title of '; 'the example'};
            app.ExListBox = uilistbox(app.TabExamples);
            app.ExListBox.ValueChangedFcn = createCallbackFcn(app, @ExListBoxValueChanged, true);
            app.TabExtraExamples = uitab(app.TabGroup);
            app.TabExtraExamples.Title = 'Extra Examples';
            app.TabExtraExamples.Units = 'pixels';
            app.Label5_4 = uilabel(app.TabExtraExamples);
            app.Label5_4.Text = {'Check this box if the example code '; 'has to be executed '; 'and its output must be shown '; 'in the HTML output file'};
            app.Label7_6 = uilabel(app.TabExtraExamples);
            app.Label7_6.Text = 'Write here the purpose of the example together with a description';
            app.Label8_4 = uilabel(app.TabExtraExamples);
            app.Label8_4.Text = 'Insert here the MATLAB code (adding comments with usual symbol %)';
            app.ExtraExtoExecute = uicheckbox(app.TabExtraExamples);
            app.ExtraExtoExecute.ValueChangedFcn = createCallbackFcn(app, @ExtraExtoExecuteValueChanged, true);
            app.ExtraExtoExecute.Text = 'Example to Execute';
            app.AddExtraEx = uibutton(app.TabExtraExamples, 'push');
            app.AddExtraEx.ButtonPushedFcn = createCallbackFcn(app, @AddExtraExButtonPushed, true);
            app.AddExtraEx.Text = 'Add Extra Example';
            app.DelExtraEx = uibutton(app.TabExtraExamples, 'push');
            app.DelExtraEx.ButtonPushedFcn = createCallbackFcn(app, @DelExtraExButtonPushed, true);
            app.DelExtraEx.Text = {'Delete Selected '; 'Extra example'};
            app.ExMatlabCode_2Label = uilabel(app.TabExtraExamples);
            app.ExMatlabCode_2Label.Text = {'Matlab code '; '(with comments)'};
            app.ExtraExMatlabCode = uitextarea(app.TabExtraExamples);
            app.ExtraExampledescriptionLabel = uilabel(app.TabExtraExamples);
            app.ExtraExampledescriptionLabel.Text = 'Extra Example description';
            app.ExtraExDescription = uitextarea(app.TabExtraExamples);
            app.TitleoftheextraexampleLabel = uilabel(app.TabExtraExamples);
            app.TitleoftheextraexampleLabel.Text = {'Title of '; 'the extra'; 'example'};
            app.ExtraExListBox = uilistbox(app.TabExtraExamples);
            app.ExtraExListBox.ValueChangedFcn = createCallbackFcn(app, @ExtraExListBoxValueChanged, true);
            app.SavechangestoXMLfileButton = uibutton(app.UIFigure, 'push');
            app.SavechangestoXMLfileButton.ButtonPushedFcn = createCallbackFcn(app, @SavechangestoXMLfileButtonPushed, true);
            app.SavechangestoXMLfileButton.Text = 'Save changes to XML file';
        end
    end

    methods (Access = public)

        % Construct app
        function app = HelpGUI()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end